classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelOutputsKineticsModelingResultsLabel  matlab.ui.control.Label
        ComputerModelLabel              matlab.ui.control.Label
        ExperimentalProtocolDataLabel   matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroupCalibration             matlab.ui.container.TabGroup
        ModelOutputTab                  matlab.ui.container.Tab
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        VoltageDropDown                 matlab.ui.control.DropDown
        VoltageDropDownLabel            matlab.ui.control.Label
        ModelParametersImportButton     matlab.ui.control.Button
        ModelParametersSaveButton       matlab.ui.control.Button
        AutomaticCalibrationRunButton   matlab.ui.control.Button
        UIAxesModelSum                  matlab.ui.control.UIAxes
        UIAxesModelIndv                 matlab.ui.control.UIAxes
        Kv42TabCalib                    matlab.ui.container.Tab
        Kv15Kv21K2PTabCalib             matlab.ui.container.Tab
        CenterPanel                     matlab.ui.container.Panel
        TabGroupComputerModel           matlab.ui.container.TabGroup
        Kv42Tab                         matlab.ui.container.Tab
        IncludeKto                      matlab.ui.control.CheckBox
        LabelKto                        matlab.ui.control.Label
        p2p3p5KtoEditField3             matlab.ui.control.NumericEditField
        p2p3p5KtoEditField2             matlab.ui.control.NumericEditField
        p2p3p5KtoEditField1             matlab.ui.control.NumericEditField
        p14KtoEditField2                matlab.ui.control.NumericEditField
        p14KtoEditField1                matlab.ui.control.NumericEditField
        p13KtoEditField2                matlab.ui.control.NumericEditField
        p13KtoEditField1                matlab.ui.control.NumericEditField
        p12KtoEditField2                matlab.ui.control.NumericEditField
        p12KtoEditField1                matlab.ui.control.NumericEditField
        p11KtoEditField2                matlab.ui.control.NumericEditField
        p11KtoEditField1                matlab.ui.control.NumericEditField
        p10KtoEditField2                matlab.ui.control.NumericEditField
        p10KtoEditField1                matlab.ui.control.NumericEditField
        p9KtoEditField2                 matlab.ui.control.NumericEditField
        p9KtoEditField1                 matlab.ui.control.NumericEditField
        p8KtoEditField2                 matlab.ui.control.NumericEditField
        p8KtoEditField1                 matlab.ui.control.NumericEditField
        p7KtoEditField2                 matlab.ui.control.NumericEditField
        p7KtoEditField1                 matlab.ui.control.NumericEditField
        p6KtoEditField8                 matlab.ui.control.NumericEditField
        p6KtoEditField7                 matlab.ui.control.NumericEditField
        p6KtoEditField6                 matlab.ui.control.NumericEditField
        p6KtoEditField5                 matlab.ui.control.NumericEditField
        p6KtoEditField4                 matlab.ui.control.NumericEditField
        p6KtoEditField3                 matlab.ui.control.NumericEditField
        p6KtoEditField2                 matlab.ui.control.NumericEditField
        p6KtoEditField1                 matlab.ui.control.NumericEditField
        p2p5KtoEditField                matlab.ui.control.NumericEditField
        p1p4KtoEditField2               matlab.ui.control.NumericEditField
        p1p4KtoEditField1               matlab.ui.control.NumericEditField
        p2p3KtoEditField3               matlab.ui.control.NumericEditField
        p2p3KtoEditField2               matlab.ui.control.NumericEditField
        p2p3KtoEditField1               matlab.ui.control.NumericEditField
        p2KtoEditField                  matlab.ui.control.NumericEditField
        p1KtoEditField2                 matlab.ui.control.NumericEditField
        p1KtoEditField1                 matlab.ui.control.NumericEditField
        fKto2                           matlab.ui.control.NumericEditField
        fKto1                           matlab.ui.control.NumericEditField
        GKtopEditField                  matlab.ui.control.NumericEditField
        GKtoEditField                   matlab.ui.control.NumericEditField
        IktofEq1                        matlab.ui.control.Image
        IktofEq2                        matlab.ui.control.Image
        Kv15Kv21K2PTab                  matlab.ui.container.Tab
        IncludeKss                      matlab.ui.control.CheckBox
        IncludeKslow2                   matlab.ui.control.CheckBox
        IncludeKslow1                   matlab.ui.control.CheckBox
        p3Kslow1EditField4              matlab.ui.control.NumericEditField
        p3Kslow1EditField3              matlab.ui.control.NumericEditField
        p4KssEditField                  matlab.ui.control.NumericEditField
        p3KssEditField                  matlab.ui.control.NumericEditField
        p2KssEditField                  matlab.ui.control.NumericEditField
        p1KssEditField2                 matlab.ui.control.NumericEditField
        p1KssEditField1                 matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        LabelModel23                    matlab.ui.control.Label
        LabelModel22                    matlab.ui.control.Label
        LabelModel21                    matlab.ui.control.Label
        p3Kslow2EditField               matlab.ui.control.NumericEditField
        p2Kslow2EditField               matlab.ui.control.NumericEditField
        p1Kslow2EditField               matlab.ui.control.NumericEditField
        Ikslow2Eq                       matlab.ui.control.Image
        p13Kslow1EditField              matlab.ui.control.NumericEditField
        p12Kslow1EditField              matlab.ui.control.NumericEditField
        p11Kslow1EditField2             matlab.ui.control.NumericEditField
        p11Kslow1EditField1             matlab.ui.control.NumericEditField
        p10Kslow1EditField              matlab.ui.control.NumericEditField
        p9Kslow1EditField               matlab.ui.control.NumericEditField
        p8Kslow1EditField               matlab.ui.control.NumericEditField
        p7Kslow1EditField2              matlab.ui.control.NumericEditField
        p7Kslow1EditField1              matlab.ui.control.NumericEditField
        p6Kslow1EditField               matlab.ui.control.NumericEditField
        p5Kslow1EditField2              matlab.ui.control.NumericEditField
        p5Kslow1EditField1              matlab.ui.control.NumericEditField
        p4Kslow1EditField               matlab.ui.control.NumericEditField
        p3Kslow1EditField2              matlab.ui.control.NumericEditField
        p3Kslow1EditField1              matlab.ui.control.NumericEditField
        p2Kslow1EditField2              matlab.ui.control.NumericEditField
        p2Kslow1EditField1              matlab.ui.control.NumericEditField
        p1Kslow1EditField               matlab.ui.control.NumericEditField
        Ikslow1Eq2                      matlab.ui.control.Image
        Ikslow1Eq1                      matlab.ui.control.Image
        LeftPanel                       matlab.ui.container.Panel
        ExperimentalDataPatchclampCurrentRecordingsPanel  matlab.ui.container.Panel
        DatauploadButton                matlab.ui.control.Button
        UIAxesExpData                   matlab.ui.control.UIAxes
        ProtocolPanel                   matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        ClampingvoltagemaxmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltagemaxmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagestepmVEditField  matlab.ui.control.NumericEditField
        ClampingvoltagestepmVEditFieldLabel  matlab.ui.control.Label
        NernstpotentialmVEditField      matlab.ui.control.NumericEditField
        NernstpotentialmVEditFieldLabel  matlab.ui.control.Label
        ClampingtimemsEditField         matlab.ui.control.NumericEditField
        ClampingtimemsEditFieldLabel    matlab.ui.control.Label
        ClampingvoltageminmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltageminmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField     matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingtimemsEditField          matlab.ui.control.NumericEditField
        HoldingtimemsEditFieldLabel     matlab.ui.control.Label
        UIAxesProtocol                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % time space
        t
        hold_t
        pulse_t
        tt

        % voltage space
        volts
        num_volts
        volt_idx

        % experimental data
        exp_t
        exp_yksum_set
        exp_volts
        exp_num_volts
        exp_idx

        % currents
        yksum_set
        ykto_set
        ykslow1_set
        ykslow2_set
        ykss_set
        yk1_set

        % current flags to be included
        ykto_idx
        ykslow1_idx
        ykslow2_idx
        ykss_idx
        ykur_idx
        yk1_idx
        
        % current parameters
        pkto
        pkslow1
        pkslow2
        pkss
        
        % parameter bounds
        lb_kto
        lb_kslow1
        lb_kslow2
        lb_kss
        lb_kur
        lb_k1
        
        ub_kto
        ub_kslow1
        ub_kslow2
        ub_kss
        ub_kur
        ub_k1

        % external apps
        NanWarnApp
        VoltWarnApp
    end
    
    methods (Access = private)
        function [t, hold_t, pulse_t] = gen_time_space(app)
            hold_len = app.HoldingtimemsEditField.Value;
            time_len = hold_len + app.ClampingtimemsEditField.Value;
            
            hold_t = 0:hold_len;
            pulse_t = (hold_len+1):time_len;
            pulse_t_shift = pulse_t - pulse_t(1);
            
            t = [hold_t, pulse_t];
            pulse_t = pulse_t_shift;
        end

        function [volts, num_volts] = gen_volt_space(app)
            minv = app.ClampingvoltageminmVEditField.Value;
            stepv = app.ClampingvoltagestepmVEditField.Value;
            maxv = app.ClampingvoltagemaxmVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function y = gen_ikto(app, v)
            if app.ykto_idx == 0
                y = zeros(length(app.t), 1);
            else
                y = ikto(app.pkto, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end
        end
        
        function y = gen_ikslow1(app, v)
            if app.ykslow1_idx == 0
                y = zeros(length(app.t), 1);
            else
                y = ikslow1(app.pkslow1, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end            
        end
        
        function y = gen_ikslow2(app, v)
            if app.ykslow2_idx == 0
                y = zeros(length(app.t), 1);
            else
                y = ikslow2(app.pkslow2, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end
        end
        
        function y = gen_ikss(app, v)
            if app.ykss_idx == 0
                y = zeros(length(app.t), 1);
            else
                y = ikss(app.pkss, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end
        end
      
        function yset = gen_currents_over_volts(app, current_generator)
            % wrapper function looping over voltages
            yset = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                y = current_generator(app, app.volts(i));
                if any(isnan(y))    
                    % warning message for NaN
                    app.NanWarnApp = warn_message_nan();
                    break
                else
                    yset(:,i) = y;
                end
            end
        end

        function update_sum_current(app)
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.ykto_set(:,i) + app.ykslow1_set(:,i) + ...
                    app.ykslow2_set(:,i) + app.ykss_set(:,i) + app.yk1_set(:,i);
            end
        end

        function plot_protocol(app)
            yhold = ones(length(app.hold_t), 1)*app.HoldingpotentialmVEditField.Value;
            ypulse = ones(length(app.pulse_t), 1)*app.volts(1);
            y = [yhold; ypulse];
            plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesProtocol, "on")
            for i=2:app.num_volts
                ypulse = ones(length(app.pulse_t), 1)*app.volts(i);
                y = [yhold; ypulse];
                plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesProtocol, "off")            
        end
    
        function plot_currents_onevolt(app, volt_idx)
            % check given voltage is out of voltage range of exp_volts
            if app.exp_idx == true
                if ~ismember(app.volts(volt_idx), app.exp_volts)
                    volt_idx = 1;
                    app.VoltageDropDown.Value = app.VoltageDropDown.Items(1);
                    app.WarnAppExpVolt = warn_message_exp_volt(app);
                end
            end
            
            % figure 1: individual K+ currents
            plot_list = [];
            legend_list = [];
            
            % find first current to be plotted
            current_idx_set = [app.ykto_idx, app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx, app.yk1_idx];
            active_current_idx = find(current_idx_set == 1);
            
            % first plot before hold on
            if isempty(active_current_idx)
                plot(app.UIAxesModelIndv, app.t, zeros(length(app.t), 1))
            else
                switch active_current_idx(1)
                    case 1
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykto_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kto}"];
                    case 2
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kslow1}"];
                    case 3
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kslow2}"];
                    case 4
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kss}"];
                    case 5
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.yk1_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{K1}"];                        
                end
                
                hold(app.UIAxesModelIndv, "on")
                for i = 2:length(active_current_idx)
                    switch active_current_idx(i)
                        case 1
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykto_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kto}"];
                        case 2
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kslow1}"];
                        case 3
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kslow2}"];
                        case 4
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kss}"];
                        case 5
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.yk1_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{K1}"];
                    end
                end
                hold(app.UIAxesModelIndv, "off")
                axis(app.UIAxesModelIndv, "tight")
                legend(app.UIAxesModelIndv, legend_list, "Location","northeast")                
            end
            
            % figure 2: iksum
            if app.exp_idx == true
                plot(app.UIAxesModelSum, app.exp_t, app.exp_yksum_set(:,volt_idx), "LineWidth",2)
                hold(app.UIAxesModelSum, "on")
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                hold(app.UIAxesModelSum, "off")
                axis(app.UIAxesModelSum, "tight")
                legend(app.UIAxesModelSum, "Experimental Data", "Model Prediction", "Location","northeast")
            else
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesModelSum, "tight")
                legend(app.UIAxesModelSum, "Model Prediction", "Location","northeast")                
            end
        end
        
        function scaledp = scale_param(app, unitp, model_struct)
            num_currents = length(model_struct);
            [num_pt, num_var] = size(unitp);
            scaledp = NaN(num_pt, num_var);

            for i = 1:num_pt
                row = NaN(1, num_var);
                for j = 1:num_currents
                    switch model_struct(j).name
                    case 'ikto'
                        lb = app.lb_kto(model_struct(j).idx1);
                        ub = app.ub_kto(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikslow1'
                        lb = app.lb_kslow1(model_struct(j).idx1);
                        ub = app.ub_kslow1(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikslow2'
                        lb = app.lb_kslow2(model_struct(j).idx1);
                        ub = app.ub_kslow2(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikss'
                        lb = app.lb_kss(model_struct(j).idx1);
                        ub = app.ub_kss(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikur'
                        lb = app.lb_kur(model_struct(j).idx1);
                        ub = app.ub_kur(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ik1'
                        lb = app.lb_k1(model_struct(j).idx1);
                        ub = app.ub_k1(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    end
                end
                scaledp(i, :) = row;
            end
        end
        
        function update_pkto(app, sol)
            app.p2p3p5KtoEditField3.Value = sol(2)+sol(3)-sol(5);
            app.p2p3p5KtoEditField2.Value = sol(2)+sol(3)-sol(5);
            app.p2p3p5KtoEditField1.Value = sol(2)+sol(3)-sol(5);
            app.p14KtoEditField2.Value = sol(14);                
            app.p14KtoEditField1.Value = sol(14);                
            app.p13KtoEditField2.Value = sol(13);                
            app.p13KtoEditField1.Value = sol(13);                
            app.p12KtoEditField2.Value = sol(12);                
            app.p12KtoEditField1.Value = sol(12);                
            app.p11KtoEditField2.Value = sol(11);                
            app.p11KtoEditField1.Value = sol(11);                
            app.p10KtoEditField2.Value = sol(10);                
            app.p10KtoEditField1.Value = sol(10);                
            app.p9KtoEditField2.Value = sol(9);                 
            app.p9KtoEditField1.Value = sol(9);                 
            app.p8KtoEditField2.Value = sol(8);                 
            app.p8KtoEditField1.Value = sol(8);                 
            app.p7KtoEditField2.Value = sol(7);                 
            app.p7KtoEditField1.Value = sol(7);                 
            app.p6KtoEditField8.Value = sol(6);                 
            app.p6KtoEditField7.Value = sol(6);                 
            app.p6KtoEditField6.Value = sol(6);                 
            app.p6KtoEditField5.Value = sol(6);                 
            app.p6KtoEditField4.Value = sol(6);                 
            app.p6KtoEditField3.Value = sol(6);                 
            app.p6KtoEditField2.Value = sol(6);                 
            app.p6KtoEditField1.Value = sol(6);                 
            app.p2p5KtoEditField.Value = sol(1)-sol(5);                
            app.p1p4KtoEditField2.Value = sol(1)-sol(4);               
            app.p1p4KtoEditField1.Value = sol(1)-sol(4);               
            app.p2p3KtoEditField3.Value = sol(2)+sol(3);               
            app.p2p3KtoEditField2.Value = sol(2)+sol(3);               
            app.p2p3KtoEditField1.Value = sol(2)+sol(3);         
            app.p2KtoEditField.Value = sol(2);                  
            app.p1KtoEditField2.Value = sol(1);                 
            app.p1KtoEditField1.Value = sol(1);                 
            app.fKto2.Value = sol(15);        
            app.fKto1.Value = sol(15);        
            app.GKtopEditField.Value = sol(17);                  
            app.GKtoEditField.Value = sol(16);                       
        end

        function update_pkslow1(app, sol)
            app.p13Kslow1EditField.Value = sol(13);
            app.p12Kslow1EditField.Value = sol(12);
            app.p11Kslow1EditField2.Value = sol(11);
            app.p11Kslow1EditField1.Value = sol(11);
            app.p10Kslow1EditField.Value = sol(10);
            app.p9Kslow1EditField.Value = sol(9);
            app.p8Kslow1EditField.Value = sol(8);
            app.p7Kslow1EditField2.Value = sol(7);
            app.p7Kslow1EditField1.Value = sol(7);
            app.p6Kslow1EditField.Value = sol(6);
            app.p5Kslow1EditField2.Value = sol(5);
            app.p5Kslow1EditField1.Value = sol(5);
            app.p4Kslow1EditField.Value = sol(4);
            app.p3Kslow1EditField4.Value = sol(3);              
            app.p3Kslow1EditField3.Value = sol(3);              
            app.p3Kslow1EditField2.Value = sol(3);
            app.p3Kslow1EditField1.Value = sol(3);
            app.p2Kslow1EditField2.Value = sol(2);
            app.p2Kslow1EditField1.Value = sol(2);
            app.p1Kslow1EditField.Value = sol(1);
        end

        function update_pkslow2(app, sol)
            app.p3Kslow2EditField.Value = sol(3);
            app.p2Kslow2EditField.Value = sol(2);
            app.p1Kslow2EditField.Value = sol(1);            
        end

        function update_pkss(app, sol)
            app.p4KssEditField.Value = sol(4);
            app.p3KssEditField.Value = sol(3);
            app.p2KssEditField.Value = sol(2);
            app.p1KssEditField2.Value = sol(1);
            app.p1KssEditField1.Value = sol(1);
        end        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % generate time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt = cell(1,3);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;

            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.VoltageDropDown.Items = volt_dd_items;

            % plot default experimental protocol
            plot_protocol(app);

            % check currents to be included
            app.ykto_idx = app.IncludeKto.Value;
            app.ykslow1_idx = app.IncludeKslow1.Value;
            app.ykslow2_idx = app.IncludeKslow2.Value;
            app.ykss_idx = app.IncludeKss.Value;
            app.ykur_idx = 0;
            app.yk1_idx = 0;
            
            % assign kto parameters
            app.pkto = zeros(17,1);
            app.pkto(1) = app.p1KtoEditField1.Value;
            app.pkto(2) = app.p2KtoEditField.Value;
            app.pkto(3) = app.p2p3KtoEditField1.Value - app.pkto(2);
            app.pkto(4) = app.pkto(1) - app.p1p4KtoEditField1.Value;
            app.pkto(5) = app.pkto(2) - app.p2p5KtoEditField.Value;
            app.pkto(6) = app.p6KtoEditField1.Value;
            app.pkto(7) = app.p7KtoEditField1.Value;
            app.pkto(8) = app.p8KtoEditField1.Value;
            app.pkto(9) = app.p9KtoEditField1.Value;
            app.pkto(10) = app.p10KtoEditField1.Value;
            app.pkto(11) = app.p11KtoEditField1.Value;
            app.pkto(12) = app.p12KtoEditField1.Value;
            app.pkto(13) = app.p13KtoEditField1.Value;
            app.pkto(14) = app.p14KtoEditField1.Value;
            app.pkto(15) = app.fKto1.Value;
            app.pkto(16) = app.GKtoEditField.Value;
            app.pkto(17) = app.GKtopEditField.Value;
            
            % assign kslow1 parameters
            app.pkslow1 = zeros(13,1);
            app.pkslow1(1) = app.p1Kslow1EditField.Value; % shared
            app.pkslow1(2) = app.p2Kslow1EditField1.Value;
            app.pkslow1(3) = app.p3Kslow1EditField1.Value;
            app.pkslow1(4) = app.p4Kslow1EditField.Value;
            app.pkslow1(5) = app.p5Kslow1EditField1.Value;
            app.pkslow1(6) = app.p6Kslow1EditField.Value;
            app.pkslow1(7) = app.p7Kslow1EditField1.Value;
            app.pkslow1(8) = app.p8Kslow1EditField.Value;
            app.pkslow1(9) = app.p9Kslow1EditField.Value; % unique
            app.pkslow1(10) = app.p10Kslow1EditField.Value;
            app.pkslow1(11) = app.p11Kslow1EditField1.Value;
            app.pkslow1(12) = app.p12Kslow1EditField.Value;
            app.pkslow1(13) = app.p13Kslow1EditField.Value;
            
            % assign kslow2 parameters
            app.pkslow2 = zeros(11,1);
            app.pkslow2(1) = app.p1Kslow1EditField.Value; % shared
            app.pkslow2(2) = app.p2Kslow1EditField1.Value;
            app.pkslow2(3) = app.p3Kslow1EditField1.Value;
            app.pkslow2(4) = app.p4Kslow1EditField.Value;
            app.pkslow2(5) = app.p5Kslow1EditField1.Value;
            app.pkslow2(6) = app.p6Kslow1EditField.Value;
            app.pkslow2(7) = app.p7Kslow1EditField1.Value;
            app.pkslow2(8) = app.p8Kslow1EditField.Value;
            app.pkslow2(9) = app.p1Kslow2EditField.Value; % unique
            app.pkslow2(10) = app.p2Kslow2EditField.Value;
            app.pkslow2(11) = app.p3Kslow2EditField.Value;

            % assign kss parameters
            app.pkss = zeros(7,1);
            app.pkss(1) = app.p1Kslow1EditField.Value; % shared
            app.pkss(2) = app.p3Kslow1EditField1.Value;
            app.pkss(3) = app.p4Kslow1EditField.Value;
            app.pkss(4) = app.p1KssEditField1.Value; % unique
            app.pkss(5) = app.p2KssEditField.Value;
            app.pkss(6) = app.p3KssEditField.Value;
            app.pkss(7) = app.p4KssEditField.Value;

            % generate individual currents
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            app.yk1_set = zeros(length(app.t),app.num_volts);

            % calculate iksum
            update_sum_current(app);
            
            % plot currents
            app.volt_idx = 1;
            plot_currents_onevolt(app, app.volt_idx);

            app.p1p4KtoEditField1.Editable = 'off';
            app.p1p4KtoEditField2.Editable = 'off';
            app.p2p3KtoEditField1.Editable = 'off';
            app.p2p3KtoEditField2.Editable = 'off';
            app.p2p3KtoEditField3.Editable = 'off';
            app.p2p5KtoEditField.Editable = 'off';
            app.p2p3p5KtoEditField1.Editable = 'off';
            app.p2p3p5KtoEditField2.Editable = 'off';
            app.p2p3p5KtoEditField3.Editable = 'off';
            app.p3Kslow1EditField3.Editable = 'off';
            app.p3Kslow1EditField4.Editable = 'off';

            % parameter bounds
            app.lb_kto = [-70, -70, -70, -10, eps, 1, eps, eps, eps, eps, eps, eps, eps, eps, eps, eps, eps];
            app.lb_kslow1 = [-70, -70, -70, 1, 1, eps, eps, eps, eps, eps, eps, eps, eps];
            app.lb_kslow2 = [5000, eps, eps];
            app.lb_kss = [eps, eps, eps, eps];
            app.lb_kur = [eps, 500, eps];
            app.lb_k1 = [eps, -20, eps, -30, eps, eps, eps, eps, eps, eps];
            
            app.ub_kto = [70, 70, 70, 50, 50, 30, 1, 1, 1, 10, 0.005, 0.3, 0.005, 0.5, 1, 1, 1];
            app.ub_kslow1 = [50, 50, 50, 10, 50, 50, 1, 100, 1000, 50, 1, 1, 1];
            app.ub_kslow2 = [10000, 5000, 1];
            app.ub_kss = [1, 2000, 100, 1];
            app.ub_kur = [500, 2000, 1];
            app.ub_k1 = [120, 30, 1000, 30, 3, 1, 2, 0.25, 0.25, 1];         
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % update time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt = cell(1,3);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;
            
            % update voltages
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.VoltageDropDown.Items = volt_dd_items;

            % update protocol figure
            plot_protocol(app);

            % update currents
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            
            % update currents figure
            app.volt_idx = 1;
            app.VoltageDropDown.Value = volt_dd_items(1);
            plot_currents_onevolt(app, app.volt_idx);

            if (app.exp_idx == true)
                if (app.num_volts ~= app.exp_num_volts)
                    app.VoltWarnApp = warn_message_exp_volt;
                    cla(app.UIAxesExpData)
                    app.exp_idx = false;
                    plot_currents_onevolt(app, app.volt_idx);
                end
            end
        end

        % Button pushed function: DatauploadButton
        function DatauploadButtonPushed(app, event)
            [file,path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
            
            % when close file selection window withou selecting file
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % load experimental data
            exp_data = table2array(readtable(fullfile(path, file)));
            app.exp_t = exp_data(:,1);
            app.exp_yksum_set = exp_data(:,2:end);
            
            % meta info
            app.exp_idx = true;
            [~, app.exp_num_volts] = size(app.exp_yksum_set);
            
            % estimate voltages of experimental data
            min_volt = app.ClampingvoltageminmVEditField.Value;
            volt_step = app.ClampingvoltagestepmVEditField.Value;
            app.exp_volts = min_volt:volt_step:(min_volt + (app.exp_num_volts-1)*volt_step);
            
            % select data for calibration
            if app.exp_num_volts ~= app.num_volts
                app.VoltWarnApp = warn_message_exp_volt;
                app.exp_idx = false;
            else
                % visualize experimental data
                plot(app.UIAxesExpData, app.exp_t, app.exp_yksum_set(:,1))
                hold(app.UIAxesExpData, "on")
                for i=2:app.exp_num_volts
                    plot(app.UIAxesExpData, app.exp_t, app.exp_yksum_set(:,i))
                end
                hold(app.UIAxesExpData, "off")
                axis(app.UIAxesExpData, "tight")
                
                plot_currents_onevolt(app, app.volt_idx);            
            end
        end

        % Button pushed function: AutomaticCalibrationRunButton
        function AutomaticCalibrationRunButtonPushed(app, event)
            if app.exp_idx == 0
                error('No experimental data')
            end

            % progress bar %
            % Store original button text
            orig_button_text = app.AutomaticCalibrationRunButton.Text;

            % When the function ends, return the original button state
            cleanup = onCleanup(@()set(app.AutomaticCalibrationRunButton, 'Text',orig_button_text, 'Icon',''));

            % Change button name to "Processing"
            app.AutomaticCalibrationRunButton.Text = 'Processing...';
            
            % Put text on top of icon
            app.AutomaticCalibrationRunButton.IconAlignment = 'bottom';

            % Create waitbar with same color as button
            wbar = permute(repmat(app.AutomaticCalibrationRunButton.BackgroundColor,15,1,1000),[1,3,2]);

            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;

            % Load the empty waitbar to the button
            app.AutomaticCalibrationRunButton.Icon = wbar;
            pause(0.1)

            % current model structure %
            current_idx_set = [app.ykto_idx, app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx, app.ykur_idx, app.yk1_idx];
            current_names = {'ikto', 'ikslow1', 'ikslow2', 'ikss', 'ikur', 'ik1'};
            delete_idx = [];
            for i = 1:length(current_idx_set)
                if current_idx_set(i) == 0
                    delete_idx = [delete_idx,i];
                end
            end
            current_names(delete_idx) = [];

            % tunning index in individual current models
            num_currents = length(current_names);
            tune_idx1_kto = [1, 2, 6, 10, 13, 14, 15, 16, 17];
            tune_idx1_kslow1 = [1, 2, 3, 4, 5, 8, 9, 11, 12, 13];
            tune_idx1_kslow2 = [1, 3];
            tune_idx1_kss = [3, 4];
            tune_idx1_kur = [1, 3];
            tune_idx1_k1 = [1, 3, 5, 7];
            
            idx_info1 = cell(1, num_currents);
            for i = 1:num_currents
                switch current_names{i}
                case 'ikto'
                    idx_info1{i} = tune_idx1_kto;
                case 'ikslow1'
                    idx_info1{i} = tune_idx1_kslow1;
                case 'ikslow2'
                    idx_info1{i} = tune_idx1_kslow2;
                case 'ikss'
                    idx_info1{i} = tune_idx1_kss;
                case 'ikur'
                    idx_info1{i} = tune_idx1_kur;
                case 'ik1'
                    idx_info1{i} = tune_idx1_k1;
                end
            end

            % tunning index in decision variable, p
            idx_info2 = cell(1, num_currents);
            cul_idx_len = 0;
            for i = 1: num_currents
                cul_idx_len_old = cul_idx_len;
                cul_idx_len = cul_idx_len + length(idx_info1{i});
                idx_info2{i} = (1+cul_idx_len_old):(cul_idx_len);
            end

            % create model structure
            model_info = [current_names; idx_info1; idx_info2];
            field_names = {'name', 'idx1', 'idx2'};
            model_struct = cell2struct(model_info, field_names, 1);

            % default values
            kto_default = [33, 15.5, 20, 16, 8, 7, 0.03577, 0.06237, 0.18064, 0.3956, 0.000152, 0.067083, 0.00095, 0.051335, 0.2087704319, 0.14067, 0.387];
            kslow1_default = [22.5, 45.2, 40.0, 7.7, 5.7, 6.1, 0.0629, 2.058, 803.0, 18.0, 0.9214774521, 0.05766, 0.07496];
            kslow2_default = [5334, 4912, 0.05766];
            kss_default = [0.0862, 1235.5, 13.17, 0.0428];
            kur_default = [270, 1050, 0];
            k1_default = [59.215, 5.476, 594.31, 4.753, 1.02, 0.2385, 0.8, 0.08032, 0.06175, 0.5143];
            
            % initial point and bounds for calibration parameters
            p0 = zeros(1, cul_idx_len);
            lb = zeros(1, cul_idx_len);
            ub = zeros(1, cul_idx_len);
            for i = 1:num_currents
                switch current_names{i}
                case 'ikto'
                    p0(idx_info2{i}) = kto_default(tune_idx1_kto);
                    lb(idx_info2{i}) = app.lb_kto(tune_idx1_kto);
                    ub(idx_info2{i}) = app.ub_kto(tune_idx1_kto);
                case 'ikslow1'
                    p0(idx_info2{i}) = kslow1_default(tune_idx1_kslow1);
                    lb(idx_info2{i}) = app.lb_kslow1(tune_idx1_kslow1);
                    ub(idx_info2{i}) = app.ub_kslow1(tune_idx1_kslow1);
                case 'ikslow2'
                    p0(idx_info2{i}) = kslow2_default(tune_idx1_kslow2);
                    lb(idx_info2{i}) = app.lb_kslow2(tune_idx1_kslow2);
                    ub(idx_info2{i}) = app.ub_kslow2(tune_idx1_kslow2);
                case 'ikss'
                    p0(idx_info2{i}) = kss_default(tune_idx1_kss);
                    lb(idx_info2{i}) = app.lb_kss(tune_idx1_kss);
                    ub(idx_info2{i}) = app.ub_kss(tune_idx1_kss);
                case 'ikur'
                    p0(idx_info2{i}) = kur_default(tune_idx1_kur);
                    lb(idx_info2{i}) = app.lb_kur(tune_idx1_kur);
                    ub(idx_info2{i}) = app.ub_kur(tune_idx1_kur);
                case 'ik1'
                    p0(idx_info2{i}) = k1_default(tune_idx1_kur);
                    lb(idx_info2{i}) = app.lb_k1(tune_idx1_k1);
                    ub(idx_info2{i}) = app.ub_k1(tune_idx1_k1);
                end    
            end
    
            % protocol info
            volt_space = cell(1,3);
            volt_space{1} = app.HoldingpotentialmVEditField.Value;
            volt_space{2} = app.volts;
            volt_space{3} = app.NernstpotentialmVEditField.Value;

            time_space = cell(1,4);
            [~, ideal_hold_idx] = min(abs(app.exp_t-app.HoldingtimemsEditField.Value)); % estimate time points
            [~, ideal_end_idx] = min(abs(app.exp_t-app.ClampingtimemsEditField.Value));
            app.t = app.exp_t(1:ideal_end_idx);
            time_space{1} = app.t;
            app.hold_t = app.t(1:ideal_hold_idx);
            time_space{2} = app.hold_t;
            app.pulse_t = app.t(ideal_hold_idx+1:end);
            pulse_t_adj = app.pulse_t-app.pulse_t(1);
            time_space{3} = pulse_t_adj;
            time_space{4} = ideal_hold_idx;
            time_space{5} = ideal_end_idx;

            % optimization options
            options = optimoptions(@fmincon, ...
                'Algorithm','interior-point', 'Display','off', ...
                'SpecifyObjectiveGradient',true);

            % optimization constraints 
            A = [];
            b = [];
            Aeq = [];
            beq = [];
            nonlcon = [];

            % objective function
            obj_fun = @(p) obj_rmse_grad(p, model_struct, volt_space, time_space, app.exp_yksum_set(1:ideal_end_idx,:));

            % run optimization
            rmse_list = zeros(app.IterationsEditField.Value, 1);
            sol_list = cell(app.IterationsEditField.Value, 1);

            % first run with p0
            [sol, fval] = fmincon(obj_fun, p0, A, b, Aeq, beq, lb, ub, nonlcon, options);
            rmse_list(1) = fval;
            sol_list{1} = sol;

            % random intialization
            running_p0 = lhsdesign(app.IterationsEditField.Value, cul_idx_len);
            running_p0 = scale_param(app, running_p0, model_struct);
            for i = 2:app.IterationsEditField.Value
                try
                   [sol,fval] = fmincon(obj_fun, running_p0(i,:), A, b, Aeq, beq, lb, ub, nonlcon, options);
                   rmse_list(i) = fval;
                   rmse_list{i} = sol;
                catch
                   rmse_list(i) = 1e+3;
                end
                % update progress bar
                current_prog = min(round((size(wbar,2)-2)*(i/app.IterationsEditField.Value)), size(wbar,2)-2); 
                app.AutomaticCalibrationRunButton.Icon(2:end-1, 2:current_prog+1, 1) = 0.25391; % (royalblue)
                app.AutomaticCalibrationRunButton.Icon(2:end-1, 2:current_prog+1, 2) = 0.41016;
                app.AutomaticCalibrationRunButton.Icon(2:end-1, 2:current_prog+1, 3) = 0.87891;
                pause(0.1)            
            end
            [~, best_fit_idx] = min(rmse_list);
            best_sol = sol_list{best_fit_idx};

            for i = 1:num_currents
                switch current_names{i}
                case 'ikto'
                    sol_kto = kto_default;
                    sol_kto(tune_idx1_kto) = best_sol(idx_info2{i});
                    update_pkto(app,sol_kto);
                case 'ikslow1'
                    sol_kslow1 = kslow1_default;
                    sol_kslow1(tune_idx1_kslow1) = best_sol(idx_info2{i});
                    update_pkslow1(app,sol_kslow1);
                case 'ikslow2'
                    sol_kslow2 = kslow2_default;
                    sol_kslow2(tune_idx1_kslow2) = best_sol(idx_info2{i});
                    update_pkslow2(app,sol_kslow2);
                case 'ikss'
                    sol_kss = kss_default;
                    sol_kss(tune_idx1_kss) = best_sol(idx_info2{i});
                    update_pkss(app,sol_kss);
                case 'ikur'
                    sol_kur = kur_default;
                    sol_kur(tune_idx1_kur) = best_sol(idx_info2{i});
                case 'ik1'
                    sol_k1 = k1_default;
                    sol_k1(tune_idx1_k1) = best_sol(idx_info2{i});
                end
            end
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            plot_currents_onevolt(app,app.volt_idx);
        end

        % Value changed function: VoltageDropDown
        function VoltageDropDownValueChanged(app, event)
            value = app.VoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_idx = find(app.volts == volt);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: IncludeKto
        function IncludeKtoValueChanged(app, event)
            value = app.IncludeKto.Value;
            app.ykto_idx = value;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);                 
        end

        % Value changed function: IncludeKslow1
        function IncludeKslow1ValueChanged(app, event)
            value = app.IncludeKslow1.Value;
            app.ykslow1_idx = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: IncludeKslow2
        function IncludeKslow2ValueChanged(app, event)
            value = app.IncludeKslow2.Value;
            app.ykslow2_idx = value;

            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: IncludeKss
        function IncludeKssValueChanged(app, event)
            value = app.IncludeKss.Value;
            app.ykss_idx = value;

            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p1KtoEditField1
        function p1KtoEditField1ValueChanged(app, event)
            value = app.p1KtoEditField1.Value;
            app.p1KtoEditField2.Value = value;
            app.p1p4KtoEditField1.Value = value-app.pkto(4);
            app.p1p4KtoEditField2.Value = value-app.pkto(4);

            app.pkto(1) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p1KtoEditField2
        function p1KtoEditField2ValueChanged(app, event)
            value = app.p1KtoEditField2.Value;
            app.p1KtoEditField1.Value = value;
            app.p1p4KtoEditField1.Value = value-app.pkto(4);
            app.p1p4KtoEditField2.Value = value-app.pkto(4);

            app.pkto(1) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p2KtoEditField
        function p2KtoEditFieldValueChanged(app, event)
            value = app.p2KtoEditField.Value;
            app.p2p3KtoEditField1.Value = value+app.pkto(3);
            app.p2p3KtoEditField2.Value = value+app.pkto(3);
            app.p2p3KtoEditField3.Value = value+app.pkto(3);
            app.p2p3p5KtoEditField1.Value = value+app.pkto(3)-app.pkto(5);
            app.p2p3p5KtoEditField2.Value = value+app.pkto(3)-app.pkto(5);
            app.p2p3p5KtoEditField3.Value = value+app.pkto(3)-app.pkto(5);
            app.p2p5KtoEditField.Value = value-app.pkto(5);
            
            app.pkto(2) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p6KtoEditField1
        function p6KtoEditField1ValueChanged(app, event)
            value = app.p6KtoEditField1.Value;
            app.p6KtoEditField2.Value = value;
            app.p6KtoEditField3.Value = value;
            app.p6KtoEditField4.Value = value;
            app.p6KtoEditField5.Value = value;
            app.p6KtoEditField6.Value = value;
            app.p6KtoEditField7.Value = value;
            app.p6KtoEditField8.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p6KtoEditField2
        function p6KtoEditField2ValueChanged(app, event)
            value = app.p6KtoEditField2.Value;
            app.p6KtoEditField1.Value = value;
            app.p6KtoEditField3.Value = value;
            app.p6KtoEditField4.Value = value;
            app.p6KtoEditField5.Value = value;
            app.p6KtoEditField6.Value = value;
            app.p6KtoEditField7.Value = value;
            app.p6KtoEditField8.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p6KtoEditField3
        function p6KtoEditField3ValueChanged(app, event)
            value = app.p6KtoEditField3.Value;
            app.p6KtoEditField1.Value = value;
            app.p6KtoEditField2.Value = value;
            app.p6KtoEditField4.Value = value;
            app.p6KtoEditField5.Value = value;
            app.p6KtoEditField6.Value = value;
            app.p6KtoEditField7.Value = value;
            app.p6KtoEditField8.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p6KtoEditField4
        function p6KtoEditField4ValueChanged(app, event)
            value = app.p6KtoEditField4.Value;
            app.p6KtoEditField1.Value = value;
            app.p6KtoEditField2.Value = value;
            app.p6KtoEditField3.Value = value;
            app.p6KtoEditField5.Value = value;
            app.p6KtoEditField6.Value = value;
            app.p6KtoEditField7.Value = value;
            app.p6KtoEditField8.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p6KtoEditField5
        function p6KtoEditField5ValueChanged(app, event)
            value = app.p6KtoEditField5.Value;
            app.p6KtoEditField1.Value = value;
            app.p6KtoEditField2.Value = value;
            app.p6KtoEditField3.Value = value;
            app.p6KtoEditField4.Value = value;
            app.p6KtoEditField6.Value = value;
            app.p6KtoEditField7.Value = value;
            app.p6KtoEditField8.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);                        
        end

        % Value changed function: p6KtoEditField6
        function p6KtoEditField6ValueChanged(app, event)
            value = app.p6KtoEditField6.Value;
            app.p6KtoEditField1.Value = value;
            app.p6KtoEditField2.Value = value;
            app.p6KtoEditField3.Value = value;
            app.p6KtoEditField4.Value = value;
            app.p6KtoEditField5.Value = value;
            app.p6KtoEditField7.Value = value;
            app.p6KtoEditField8.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p6KtoEditField7
        function p6KtoEditField7ValueChanged(app, event)
            value = app.p6KtoEditField7.Value;
            app.p6KtoEditField1.Value = value;
            app.p6KtoEditField2.Value = value;
            app.p6KtoEditField3.Value = value;
            app.p6KtoEditField4.Value = value;
            app.p6KtoEditField5.Value = value;
            app.p6KtoEditField6.Value = value;
            app.p6KtoEditField8.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);                        
        end

        % Value changed function: p6KtoEditField8
        function p6KtoEditField8ValueChanged(app, event)
            value = app.p6KtoEditField8.Value;
            app.p6KtoEditField1.Value = value;
            app.p6KtoEditField2.Value = value;
            app.p6KtoEditField3.Value = value;
            app.p6KtoEditField4.Value = value;
            app.p6KtoEditField5.Value = value;
            app.p6KtoEditField6.Value = value;
            app.p6KtoEditField7.Value = value;

            app.pkto(6) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);             
        end

        % Value changed function: p7KtoEditField1
        function p7KtoEditField1ValueChanged(app, event)
            value = app.p7KtoEditField1.Value;
            app.p7KtoEditField2.Value = value;

            app.pkto(7) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);                         
        end

        % Value changed function: p7KtoEditField2
        function p7KtoEditField2ValueChanged(app, event)
            value = app.p7KtoEditField2.Value;
            app.p7KtoEditField1.Value = value;

            app.pkto(7) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p8KtoEditField1
        function p8KtoEditField1ValueChanged(app, event)
            value = app.p8KtoEditField1.Value;
            app.p8KtoEditField2.Value = value;

            app.pkto(8) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);                        
        end

        % Value changed function: p8KtoEditField2
        function p8KtoEditField2ValueChanged(app, event)
            value = app.p8KtoEditField2.Value;
            app.p8KtoEditField1.Value = value;

            app.pkto(8) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p9KtoEditField1
        function p9KtoEditField1ValueChanged(app, event)
            value = app.p9KtoEditField1.Value;
            app.p9KtoEditField2.Value = value;

            app.pkto(9) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p9KtoEditField2
        function p9KtoEditField2ValueChanged(app, event)
            value = app.p9KtoEditField2.Value;
            app.p9KtoEditField1.Value = value;

            app.pkto(9) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p10KtoEditField1
        function p10KtoEditField1ValueChanged(app, event)
            value = app.p10KtoEditField1.Value;
            app.p10KtoEditField2.Value = value;

            app.pkto(10) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p10KtoEditField2
        function p10KtoEditField2ValueChanged(app, event)
            value = app.p10KtoEditField2.Value;
            app.p10KtoEditField1.Value = value;

            app.pkto(10) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p11KtoEditField1
        function p11KtoEditField1ValueChanged(app, event)
            value = app.p11KtoEditField1.Value;
            app.p11KtoEditField2.Value = value;

            app.pkto(11) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p11KtoEditField2
        function p11KtoEditField2ValueChanged(app, event)
            value = app.p11KtoEditField2.Value;
            app.p11KtoEditField1.Value = value;

            app.pkto(11) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p12KtoEditField1
        function p12KtoEditField1ValueChanged(app, event)
            value = app.p12KtoEditField1.Value;
            app.p12KtoEditField2.Value = value;

            app.pkto(12) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p12KtoEditField2
        function p12KtoEditField2ValueChanged(app, event)
            value = app.p12KtoEditField2.Value;
            app.p12KtoEditField1.Value = value;

            app.pkto(12) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p13KtoEditField1
        function p13KtoEditField1ValueChanged(app, event)
            value = app.p13KtoEditField1.Value;
            app.p13KtoEditField2.Value = value;

            app.pkto(13) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p13KtoEditField2
        function p13KtoEditField2ValueChanged(app, event)
            value = app.p13KtoEditField2.Value;
            app.p13KtoEditField1.Value = value;

            app.pkto(13) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p14KtoEditField1
        function p14KtoEditField1ValueChanged(app, event)
            value = app.p14KtoEditField1.Value;
            app.p14KtoEditField2.Value = value;

            app.pkto(14) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p14KtoEditField2
        function p14KtoEditField2ValueChanged(app, event)
            value = app.p14KtoEditField2.Value;
            app.p14KtoEditField1.Value = value;

            app.pkto(14) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: fKto1
        function fKto1ValueChanged(app, event)
            value = app.fKto1.Value;
            app.fKto2.Value = value;

            app.pkto(15) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: fKto2
        function fKto2ValueChanged(app, event)
            value = app.fKto2.Value;
            app.fKto1.Value = value;

            app.pkto(15) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: GKtoEditField
        function GKtoEditFieldValueChanged(app, event)
            value = app.GKtoEditField.Value;
            app.pkto(16) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: GKtopEditField
        function GKtopEditFieldValueChanged(app, event)
            value = app.GKtopEditField.Value;
            app.pkto(17) = value;
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p1Kslow1EditField
        function p1Kslow1EditFieldValueChanged(app, event)
            value = app.p1Kslow1EditField.Value;
            app.pkslow1(1) = value;
            app.pkslow2(1) = value;
            app.pkss(1) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p2Kslow1EditField1
        function p2Kslow1EditField1ValueChanged(app, event)
            value = app.p2Kslow1EditField1.Value;
            app.p2Kslow1EditField2.Value = value;
            app.pkslow1(2) = value;
            app.pkslow2(2) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p2Kslow1EditField2
        function p2Kslow1EditField2ValueChanged(app, event)
            value = app.p2Kslow1EditField2.Value;
            app.p2Kslow1EditField1.Value = value;
            app.pkslow1(2) = value;
            app.pkslow2(2) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p3Kslow1EditField1
        function p3Kslow1EditField1ValueChanged(app, event)
            value = app.p3Kslow1EditField1.Value;
            app.p3Kslow1EditField2.Value = value;
            app.p3Kslow1EditField3.Value = value;
            app.p3Kslow1EditField4.Value = value;
            
            app.pkslow1(3) = value;
            app.pkslow2(3) = value;
            app.pkss(2) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p3Kslow1EditField2
        function p3Kslow1EditField2ValueChanged(app, event)
            value = app.p3Kslow1EditField2.Value;
            app.p3Kslow1EditField1.Value = value;
            app.p3Kslow1EditField3.Value = value;
            app.p3Kslow1EditField4.Value = value;

            app.pkslow1(3) = value;
            app.pkslow2(3) = value;
            app.pkss(2) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p4Kslow1EditField
        function p4Kslow1EditFieldValueChanged(app, event)
            value = app.p4Kslow1EditField.Value;
            app.pkslow1(4) = value;
            app.pkslow2(4) = value;
            app.pkss(3) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p5Kslow1EditField1
        function p5Kslow1EditField1ValueChanged(app, event)
            value = app.p5Kslow1EditField1.Value;
            app.p5Kslow1EditField2.Value = value;
            
            app.pkslow1(5) = value;
            app.pkslow2(5) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p5Kslow1EditField2
        function p5Kslow1EditField2ValueChanged(app, event)
            value = app.p5Kslow1EditField2.Value;
            app.p5Kslow1EditField1.Value = value;

            app.pkslow1(5) = value;
            app.pkslow2(5) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p6Kslow1EditField
        function p6Kslow1EditFieldValueChanged(app, event)
            value = app.p6Kslow1EditField.Value;
            app.pkslow1(6) = value;
            app.pkslow2(6) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p7Kslow1EditField1
        function p7Kslow1EditField1ValueChanged(app, event)
            value = app.p7Kslow1EditField1.Value;
            app.p7Kslow1EditField2.Value = value;
            
            app.pkslow1(7) = value;
            app.pkslow2(7) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p7Kslow1EditField2
        function p7Kslow1EditField2ValueChanged(app, event)
            value = app.p7Kslow1EditField2.Value;
            app.p7Kslow1EditField1.Value = value;

            app.pkslow1(7) = value;
            app.pkslow2(7) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p8Kslow1EditField
        function p8Kslow1EditFieldValueChanged(app, event)
            value = app.p8Kslow1EditField.Value;
            app.pkslow1(8) = value;
            app.pkslow2(8) = value;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);

            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p9Kslow1EditField
        function p9Kslow1EditFieldValueChanged(app, event)
            value = app.p9Kslow1EditField.Value;
            app.pkslow1(9) = value;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p10Kslow1EditField
        function p10Kslow1EditFieldValueChanged(app, event)
            value = app.p10Kslow1EditField.Value;
            app.pkslow1(10) = value;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p11Kslow1EditField1
        function p11Kslow1EditField1ValueChanged(app, event)
            value = app.p11Kslow1EditField1.Value;
            app.p11Kslow1EditField2.Value = value;

            app.pkslow1(11) = value;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p11Kslow1EditField2
        function p11Kslow1EditField2ValueChanged(app, event)
            value = app.p11Kslow1EditField2.Value;
            app.p11Kslow1EditField1.Value = value;

            app.pkslow1(11) = value;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p12Kslow1EditField
        function p12Kslow1EditFieldValueChanged(app, event)
            value = app.p12Kslow1EditField.Value;
            app.pkslow1(12) = value;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p13Kslow1EditField
        function p13Kslow1EditFieldValueChanged(app, event)
            value = app.p13Kslow1EditField.Value;
            app.pkslow1(13) = value;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p1Kslow2EditField
        function p1Kslow2EditFieldValueChanged(app, event)
            value = app.p1Kslow2EditField.Value;
            app.pkslow2(9) = value;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p2Kslow2EditField
        function p2Kslow2EditFieldValueChanged(app, event)
            value = app.p2Kslow2EditField.Value;
            app.pkslow2(10) = value;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p3Kslow2EditField
        function p3Kslow2EditFieldValueChanged(app, event)
            value = app.p3Kslow2EditField.Value;
            app.pkslow2(11) = value;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p1KssEditField1
        function p1KssEditField1ValueChanged(app, event)
            value = app.p1KssEditField1.Value;
            app.p1KssEditField2.Value = value;

            app.pkss(4) = value;
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p1KssEditField2
        function p1KssEditField2ValueChanged(app, event)
            value = app.p1KssEditField2.Value;
            app.p1KssEditField1.Value = value;

            app.pkss(4) = value;
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p2KssEditField
        function p2KssEditFieldValueChanged(app, event)
            value = app.p2KssEditField.Value;
            app.pkss(5) = value;
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Value changed function: p3KssEditField
        function p3KssEditFieldValueChanged(app, event)
            value = app.p3KssEditField.Value;
            app.pkss(6) = value;
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);            
        end

        % Value changed function: p4KssEditField
        function p4KssEditFieldValueChanged(app, event)
            value = app.p4KssEditField.Value;
            app.pkss(7) = value;
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            plot_currents_onevolt(app, app.volt_idx);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.NanWarnApp)
            delete(app.VoltWarnApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.ProtocolPanel = uipanel(app.LeftPanel);
            app.ProtocolPanel.Title = 'Experimental Protocol';
            app.UIAxesProtocol = uiaxes(app.ProtocolPanel);
            title(app.UIAxesProtocol, 'Protocol')
            xlabel(app.UIAxesProtocol, 'Time (ms)')
            ylabel(app.UIAxesProtocol, 'Clamping Voltage (mV)')
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingtimemsEditField.Value = 120;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampingvoltageminmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltageminmVEditFieldLabel.Text = 'Clamping voltage min (mV)';
            app.ClampingvoltageminmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltageminmVEditField.Value = -50;
            app.ClampingtimemsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingtimemsEditFieldLabel.Text = 'Clamping time (ms)';
            app.ClampingtimemsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingtimemsEditField.ValueDisplayFormat = '%.0f';
            app.ClampingtimemsEditField.Value = 4500;
            app.NernstpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.NernstpotentialmVEditFieldLabel.Text = 'Nernst potential (mV)';
            app.NernstpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.NernstpotentialmVEditField.Value = -91.1;
            app.ClampingvoltagestepmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltagestepmVEditFieldLabel.Text = 'Clamping voltage step (mV)';
            app.ClampingvoltagestepmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltagestepmVEditField.Value = 10;
            app.ClampingvoltagemaxmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltagemaxmVEditFieldLabel.Text = 'Clamping voltage max (mV)';
            app.ClampingvoltagemaxmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltagemaxmVEditField.Value = 50;
            app.UpdateButton = uibutton(app.ProtocolPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ExperimentalDataPatchclampCurrentRecordingsPanel = uipanel(app.LeftPanel);
            app.ExperimentalDataPatchclampCurrentRecordingsPanel.Title = 'Experimental Data (Patch-clamp Current Recordings)';
            app.UIAxesExpData = uiaxes(app.ExperimentalDataPatchclampCurrentRecordingsPanel);
            title(app.UIAxesExpData, 'Normalized I_{Ksum} Recordings')
            xlabel(app.UIAxesExpData, 'Time (ms)')
            ylabel(app.UIAxesExpData, 'K^{+} Current (pA/pF)')
            app.DatauploadButton = uibutton(app.ExperimentalDataPatchclampCurrentRecordingsPanel, 'push');
            app.DatauploadButton.ButtonPushedFcn = createCallbackFcn(app, @DatauploadButtonPushed, true);
            app.DatauploadButton.Text = 'Data upload';
            app.CenterPanel = uipanel(app.UIFigure);
            app.TabGroupComputerModel = uitabgroup(app.CenterPanel);
            app.Kv42Tab = uitab(app.TabGroupComputerModel);
            app.Kv42Tab.Title = 'Kv 4.2';
            app.IktofEq2 = uiimage(app.Kv42Tab);
            app.IktofEq2.ImageSource = 'ikto_half2.png';
            app.IktofEq1 = uiimage(app.Kv42Tab);
            app.IktofEq1.ImageSource = 'ikto_half1.png';
            app.GKtoEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.GKtoEditField.ValueChangedFcn = createCallbackFcn(app, @GKtoEditFieldValueChanged, true);
            app.GKtoEditField.Value = 0.14067;
            app.GKtopEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.GKtopEditField.ValueChangedFcn = createCallbackFcn(app, @GKtopEditFieldValueChanged, true);
            app.GKtopEditField.Value = 0.387;
            app.fKto1 = uieditfield(app.Kv42Tab, 'numeric');
            app.fKto1.ValueChangedFcn = createCallbackFcn(app, @fKto1ValueChanged, true);
            app.fKto1.Value = 0.2087704319;
            app.fKto2 = uieditfield(app.Kv42Tab, 'numeric');
            app.fKto2.ValueChangedFcn = createCallbackFcn(app, @fKto2ValueChanged, true);
            app.fKto2.Value = 0.2087704319;
            app.p1KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p1KtoEditField1ValueChanged, true);
            app.p1KtoEditField1.Value = 33;
            app.p1KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p1KtoEditField2ValueChanged, true);
            app.p1KtoEditField2.Value = 33;
            app.p2KtoEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p2KtoEditField.ValueChangedFcn = createCallbackFcn(app, @p2KtoEditFieldValueChanged, true);
            app.p2KtoEditField.Value = 15.5;
            app.p2p3KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3KtoEditField1.Value = 35.5;
            app.p2p3KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3KtoEditField2.Value = 35.5;
            app.p2p3KtoEditField3 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3KtoEditField3.Value = 35.5;
            app.p1p4KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1p4KtoEditField1.Value = 17;
            app.p1p4KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1p4KtoEditField2.Value = 17;
            app.p2p5KtoEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p5KtoEditField.Value = 7.5;
            app.p6KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField1ValueChanged, true);
            app.p6KtoEditField1.Value = 7;
            app.p6KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField2ValueChanged, true);
            app.p6KtoEditField2.Value = 7;
            app.p6KtoEditField3 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField3.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField3ValueChanged, true);
            app.p6KtoEditField3.Value = 7;
            app.p6KtoEditField4 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField4.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField4ValueChanged, true);
            app.p6KtoEditField4.Value = 7;
            app.p6KtoEditField5 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField5.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField5ValueChanged, true);
            app.p6KtoEditField5.Value = 7;
            app.p6KtoEditField6 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField6.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField6ValueChanged, true);
            app.p6KtoEditField6.Value = 7;
            app.p6KtoEditField7 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField7.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField7ValueChanged, true);
            app.p6KtoEditField7.Value = 7;
            app.p6KtoEditField8 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField8.ValueChangedFcn = createCallbackFcn(app, @p6KtoEditField8ValueChanged, true);
            app.p6KtoEditField8.Value = 7;
            app.p7KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p7KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p7KtoEditField1ValueChanged, true);
            app.p7KtoEditField1.Value = 0.03577;
            app.p7KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p7KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p7KtoEditField2ValueChanged, true);
            app.p7KtoEditField2.Value = 0.03577;
            app.p8KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p8KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p8KtoEditField1ValueChanged, true);
            app.p8KtoEditField1.Value = 0.06237;
            app.p8KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p8KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p8KtoEditField2ValueChanged, true);
            app.p8KtoEditField2.Value = 0.06237;
            app.p9KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p9KtoEditField1.ValueDisplayFormat = '%11.6g';
            app.p9KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p9KtoEditField1ValueChanged, true);
            app.p9KtoEditField1.Value = 0.18064;
            app.p9KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p9KtoEditField2.ValueDisplayFormat = '%11.6g';
            app.p9KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p9KtoEditField2ValueChanged, true);
            app.p9KtoEditField2.Value = 0.18064;
            app.p10KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p10KtoEditField1.ValueDisplayFormat = '%11.6g';
            app.p10KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p10KtoEditField1ValueChanged, true);
            app.p10KtoEditField1.Value = 0.3956;
            app.p10KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p10KtoEditField2.ValueDisplayFormat = '%11.5g';
            app.p10KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p10KtoEditField2ValueChanged, true);
            app.p10KtoEditField2.Value = 0.3956;
            app.p11KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p11KtoEditField1.ValueDisplayFormat = '%11.5g';
            app.p11KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p11KtoEditField1ValueChanged, true);
            app.p11KtoEditField1.Value = 0.000152;
            app.p11KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p11KtoEditField2.ValueDisplayFormat = '%11.8g';
            app.p11KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p11KtoEditField2ValueChanged, true);
            app.p11KtoEditField2.Value = 0.000152;
            app.p12KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p12KtoEditField1.ValueDisplayFormat = '%11.7g';
            app.p12KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p12KtoEditField1ValueChanged, true);
            app.p12KtoEditField1.Value = 0.067083;
            app.p12KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p12KtoEditField2.ValueDisplayFormat = '%11.7g';
            app.p12KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p12KtoEditField2ValueChanged, true);
            app.p12KtoEditField2.Value = 0.067083;
            app.p13KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p13KtoEditField1.ValueDisplayFormat = '%11.8g';
            app.p13KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p13KtoEditField1ValueChanged, true);
            app.p13KtoEditField1.Value = 0.00095;
            app.p13KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p13KtoEditField2.ValueDisplayFormat = '%11.8g';
            app.p13KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p13KtoEditField2ValueChanged, true);
            app.p13KtoEditField2.Value = 0.00095;
            app.p14KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p14KtoEditField1.ValueDisplayFormat = '%11.8g';
            app.p14KtoEditField1.ValueChangedFcn = createCallbackFcn(app, @p14KtoEditField1ValueChanged, true);
            app.p14KtoEditField1.Value = 0.051335;
            app.p14KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p14KtoEditField2.ValueDisplayFormat = '%11.8g';
            app.p14KtoEditField2.ValueChangedFcn = createCallbackFcn(app, @p14KtoEditField2ValueChanged, true);
            app.p14KtoEditField2.Value = 0.051335;
            app.p2p3p5KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3p5KtoEditField1.ValueDisplayFormat = '%11.3g';
            app.p2p3p5KtoEditField1.Value = 27.5;
            app.p2p3p5KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3p5KtoEditField2.ValueDisplayFormat = '%11.3g';
            app.p2p3p5KtoEditField2.Value = 27.5;
            app.p2p3p5KtoEditField3 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3p5KtoEditField3.ValueDisplayFormat = '%11.3g';
            app.p2p3p5KtoEditField3.Value = 27.5;
            app.LabelKto = uilabel(app.Kv42Tab);
            app.LabelKto.Text = 'IKto,f (Kv 4.2): Transient outward K+ current';
            app.IncludeKto = uicheckbox(app.Kv42Tab);
            app.IncludeKto.ValueChangedFcn = createCallbackFcn(app, @IncludeKtoValueChanged, true);
            app.IncludeKto.Text = 'Include';
            app.IncludeKto.Value = true;
            app.Kv15Kv21K2PTab = uitab(app.TabGroupComputerModel);
            app.Kv15Kv21K2PTab.Title = 'Kv 1.5 / Kv 2.1 / K2P';
            app.Ikslow1Eq1 = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow1Eq1.ImageSource = 'ikslow1_half1.png';
            app.Ikslow1Eq2 = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow1Eq2.ImageSource = 'ikslow1_half2.png';
            app.p1Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p1Kslow1EditFieldValueChanged, true);
            app.p1Kslow1EditField.Value = 22.5;
            app.p2Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2Kslow1EditField1.ValueChangedFcn = createCallbackFcn(app, @p2Kslow1EditField1ValueChanged, true);
            app.p2Kslow1EditField1.Value = 45.2;
            app.p2Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2Kslow1EditField2.ValueChangedFcn = createCallbackFcn(app, @p2Kslow1EditField2ValueChanged, true);
            app.p2Kslow1EditField2.Value = 45.2;
            app.p3Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField1.ValueChangedFcn = createCallbackFcn(app, @p3Kslow1EditField1ValueChanged, true);
            app.p3Kslow1EditField1.Value = 40;
            app.p3Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField2.ValueChangedFcn = createCallbackFcn(app, @p3Kslow1EditField2ValueChanged, true);
            app.p3Kslow1EditField2.Value = 40;
            app.p4Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p4Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p4Kslow1EditFieldValueChanged, true);
            app.p4Kslow1EditField.Value = 7.7;
            app.p5Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p5Kslow1EditField1.ValueChangedFcn = createCallbackFcn(app, @p5Kslow1EditField1ValueChanged, true);
            app.p5Kslow1EditField1.Value = 5.7;
            app.p5Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p5Kslow1EditField2.ValueChangedFcn = createCallbackFcn(app, @p5Kslow1EditField2ValueChanged, true);
            app.p5Kslow1EditField2.Value = 5.7;
            app.p6Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p6Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p6Kslow1EditFieldValueChanged, true);
            app.p6Kslow1EditField.Value = 6.1;
            app.p7Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p7Kslow1EditField1.ValueChangedFcn = createCallbackFcn(app, @p7Kslow1EditField1ValueChanged, true);
            app.p7Kslow1EditField1.Value = 0.0629;
            app.p7Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p7Kslow1EditField2.ValueChangedFcn = createCallbackFcn(app, @p7Kslow1EditField2ValueChanged, true);
            app.p7Kslow1EditField2.Value = 0.0629;
            app.p8Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p8Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p8Kslow1EditFieldValueChanged, true);
            app.p8Kslow1EditField.Value = 2.058;
            app.p9Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p9Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p9Kslow1EditFieldValueChanged, true);
            app.p9Kslow1EditField.Value = 803;
            app.p10Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p10Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p10Kslow1EditFieldValueChanged, true);
            app.p10Kslow1EditField.Value = 18;
            app.p11Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p11Kslow1EditField1.ValueChangedFcn = createCallbackFcn(app, @p11Kslow1EditField1ValueChanged, true);
            app.p11Kslow1EditField1.Value = 0.9214774521;
            app.p11Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p11Kslow1EditField2.ValueChangedFcn = createCallbackFcn(app, @p11Kslow1EditField2ValueChanged, true);
            app.p11Kslow1EditField2.Value = 0.9214774521;
            app.p12Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p12Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p12Kslow1EditFieldValueChanged, true);
            app.p12Kslow1EditField.Value = 0.05766;
            app.p13Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p13Kslow1EditField.ValueChangedFcn = createCallbackFcn(app, @p13Kslow1EditFieldValueChanged, true);
            app.p13Kslow1EditField.Value = 0.07496;
            app.Ikslow2Eq = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow2Eq.ImageSource = 'ikslow2.png';
            app.p1Kslow2EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1Kslow2EditField.ValueChangedFcn = createCallbackFcn(app, @p1Kslow2EditFieldValueChanged, true);
            app.p1Kslow2EditField.Value = 5334;
            app.p2Kslow2EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2Kslow2EditField.ValueChangedFcn = createCallbackFcn(app, @p2Kslow2EditFieldValueChanged, true);
            app.p2Kslow2EditField.Value = 4912;
            app.p3Kslow2EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow2EditField.ValueChangedFcn = createCallbackFcn(app, @p3Kslow2EditFieldValueChanged, true);
            app.p3Kslow2EditField.Value = 0.05766;
            app.LabelModel21 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelModel21.Text = 'IKslow1 (Kv 1.5): Rapidly activating, slowly inactivating rectifier K+ current 1';
            app.LabelModel22 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelModel22.Text = 'IKslow2 (Kv 2.1): Rapidly activating, slowly inactivating rectifier K+ current 2';
            app.LabelModel23 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelModel23.Text = 'IKss (K2P): Non-inactivating steady-state K+ current';
            app.Image = uiimage(app.Kv15Kv21K2PTab);
            app.Image.ImageSource = 'ikss.png';
            app.p1KssEditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1KssEditField1.ValueChangedFcn = createCallbackFcn(app, @p1KssEditField1ValueChanged, true);
            app.p1KssEditField1.Value = 0.0862;
            app.p1KssEditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1KssEditField2.ValueChangedFcn = createCallbackFcn(app, @p1KssEditField2ValueChanged, true);
            app.p1KssEditField2.Value = 0.0862;
            app.p2KssEditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2KssEditField.ValueChangedFcn = createCallbackFcn(app, @p2KssEditFieldValueChanged, true);
            app.p2KssEditField.Value = 1235.5;
            app.p3KssEditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3KssEditField.ValueChangedFcn = createCallbackFcn(app, @p3KssEditFieldValueChanged, true);
            app.p3KssEditField.Value = 13.17;
            app.p4KssEditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p4KssEditField.ValueChangedFcn = createCallbackFcn(app, @p4KssEditFieldValueChanged, true);
            app.p4KssEditField.Value = 0.0428;
            app.p3Kslow1EditField3 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField3.Value = 40;
            app.p3Kslow1EditField4 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField4.Value = 40;
            app.IncludeKslow1 = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKslow1.ValueChangedFcn = createCallbackFcn(app, @IncludeKslow1ValueChanged, true);
            app.IncludeKslow1.Text = 'Include';
            app.IncludeKslow1.Value = true;
            app.IncludeKslow2 = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKslow2.ValueChangedFcn = createCallbackFcn(app, @IncludeKslow2ValueChanged, true);
            app.IncludeKslow2.Text = 'Include';
            app.IncludeKslow2.Value = true;
            app.IncludeKss = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKss.ValueChangedFcn = createCallbackFcn(app, @IncludeKssValueChanged, true);
            app.IncludeKss.Text = 'Include';
            app.IncludeKss.Value = true;
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroupCalibration = uitabgroup(app.RightPanel);
            app.ModelOutputTab = uitab(app.TabGroupCalibration);
            app.ModelOutputTab.Title = 'Model Output';
            app.UIAxesModelIndv = uiaxes(app.ModelOutputTab);
            title(app.UIAxesModelIndv, 'Individual K^{+} Currents')
            xlabel(app.UIAxesModelIndv, 'Time (ms)')
            ylabel(app.UIAxesModelIndv, 'Y')
            zlabel(app.UIAxesModelIndv, 'Z')
            app.UIAxesModelSum = uiaxes(app.ModelOutputTab);
            title(app.UIAxesModelSum, 'I_{Ksum}')
            xlabel(app.UIAxesModelSum, 'Time (ms)')
            ylabel(app.UIAxesModelSum, 'Y')
            zlabel(app.UIAxesModelSum, 'Z')
            app.AutomaticCalibrationRunButton = uibutton(app.ModelOutputTab, 'push');
            app.AutomaticCalibrationRunButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticCalibrationRunButtonPushed, true);
            app.AutomaticCalibrationRunButton.Text = 'Automatic Calibration Run';
            app.ModelParametersSaveButton = uibutton(app.ModelOutputTab, 'push');
            app.ModelParametersSaveButton.Text = 'Model Parameters Save';
            app.ModelParametersImportButton = uibutton(app.ModelOutputTab, 'push');
            app.ModelParametersImportButton.Text = 'Model Parameters Import';
            app.VoltageDropDownLabel = uilabel(app.ModelOutputTab);
            app.VoltageDropDownLabel.Text = 'Voltage';
            app.VoltageDropDown = uidropdown(app.ModelOutputTab);
            app.VoltageDropDown.Items = {'-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50'};
            app.VoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageDropDownValueChanged, true);
            app.VoltageDropDown.Value = '-50';
            app.IterationsEditFieldLabel = uilabel(app.ModelOutputTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.ModelOutputTab, 'numeric');
            app.IterationsEditField.Value = 30;
            app.Kv42TabCalib = uitab(app.TabGroupCalibration);
            app.Kv42TabCalib.Title = 'Kv 4.2';
            app.Kv15Kv21K2PTabCalib = uitab(app.TabGroupCalibration);
            app.Kv15Kv21K2PTabCalib.Title = 'Kv 1.5 / Kv 2.1 / K2P';
            app.ExperimentalProtocolDataLabel = uilabel(app.UIFigure);
            app.ExperimentalProtocolDataLabel.Text = 'Experimental Protocol & Data';
            app.ComputerModelLabel = uilabel(app.UIFigure);
            app.ComputerModelLabel.Text = 'Computer Model';
            app.ModelOutputsKineticsModelingResultsLabel = uilabel(app.UIFigure);
            app.ModelOutputsKineticsModelingResultsLabel.Text = 'Model Outputs & Kinetics Modeling Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end