classdef KvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelOutputsKineticsModelingResultsLabel  matlab.ui.control.Label
        ComputerModelLabel              matlab.ui.control.Label
        ExperimentalProtocolDataLabel   matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroupCalibration             matlab.ui.container.TabGroup
        ModelOutputTab                  matlab.ui.container.Tab
        RMSEEditField                   matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        VoltageDropDown                 matlab.ui.control.DropDown
        VoltageDropDownLabel            matlab.ui.control.Label
        ModelParametersImportButton     matlab.ui.control.Button
        ModelParametersSaveButton       matlab.ui.control.Button
        RunCalibrationButton            matlab.ui.control.Button
        UIAxesModelSum                  matlab.ui.control.UIAxes
        UIAxesModelIndv                 matlab.ui.control.UIAxes
        Kv42TabCalib                    matlab.ui.container.Tab
        KvKtof                          matlab.ui.container.Panel
        Kv15Kv21K2PTabCalib             matlab.ui.container.Tab
        KvKss                           matlab.ui.container.Panel
        KvKslow2                        matlab.ui.container.Panel
        KvKslow1                        matlab.ui.container.Panel
        CenterPanel                     matlab.ui.container.Panel
        TabGroupComputerModel           matlab.ui.container.TabGroup
        Kv42Tab                         matlab.ui.container.Tab
        IktofImage                      matlab.ui.control.Image
        GKtofEditField                  matlab.ui.control.NumericEditField
        p_1Label_13                     matlab.ui.control.Label
        p12EditField                    matlab.ui.control.NumericEditField
        p_1Label_12                     matlab.ui.control.Label
        p11EditField                    matlab.ui.control.NumericEditField
        p_1Label_11                     matlab.ui.control.Label
        p10EditField                    matlab.ui.control.NumericEditField
        p_1Label_10                     matlab.ui.control.Label
        p9EditField                     matlab.ui.control.NumericEditField
        p_1Label_9                      matlab.ui.control.Label
        p8EditField                     matlab.ui.control.NumericEditField
        p_1Label_8                      matlab.ui.control.Label
        p7EditField                     matlab.ui.control.NumericEditField
        p_1Label_7                      matlab.ui.control.Label
        p6EditField                     matlab.ui.control.NumericEditField
        p_1Label_6                      matlab.ui.control.Label
        p5EditField                     matlab.ui.control.NumericEditField
        p_1Label_5                      matlab.ui.control.Label
        p4EditField                     matlab.ui.control.NumericEditField
        p_1Label_4                      matlab.ui.control.Label
        p3EditField                     matlab.ui.control.NumericEditField
        p_1Label_3                      matlab.ui.control.Label
        p2EditField                     matlab.ui.control.NumericEditField
        p_1Label_2                      matlab.ui.control.Label
        p1EditField                     matlab.ui.control.NumericEditField
        p_1Label                        matlab.ui.control.Label
        IncludeKtof                     matlab.ui.control.CheckBox
        LabelKtof                       matlab.ui.control.Label
        Kv15Kv21K2PTab                  matlab.ui.container.Tab
        GKssEditField                   matlab.ui.control.NumericEditField
        p_1Label_31                     matlab.ui.control.Label
        p3EditField4                    matlab.ui.control.NumericEditField
        p_1Label_30                     matlab.ui.control.Label
        p2EditField4                    matlab.ui.control.NumericEditField
        p_1Label_29                     matlab.ui.control.Label
        p1EditField4                    matlab.ui.control.NumericEditField
        p_1Label_28                     matlab.ui.control.Label
        GKslow2EditField                matlab.ui.control.NumericEditField
        p_1Label_27                     matlab.ui.control.Label
        p2EditField3                    matlab.ui.control.NumericEditField
        p_1Label_26                     matlab.ui.control.Label
        p1EditField3                    matlab.ui.control.NumericEditField
        p_1Label_25                     matlab.ui.control.Label
        GKslow1EditField                matlab.ui.control.NumericEditField
        p_1Label_24                     matlab.ui.control.Label
        p10EditField2                   matlab.ui.control.NumericEditField
        p_1Label_23                     matlab.ui.control.Label
        p9EditField2                    matlab.ui.control.NumericEditField
        p_1Label_22                     matlab.ui.control.Label
        p8EditField2                    matlab.ui.control.NumericEditField
        p_1Label_21                     matlab.ui.control.Label
        p7EditField2                    matlab.ui.control.NumericEditField
        p_1Label_20                     matlab.ui.control.Label
        p6EditField2                    matlab.ui.control.NumericEditField
        p_1Label_19                     matlab.ui.control.Label
        p5EditField2                    matlab.ui.control.NumericEditField
        p_1Label_18                     matlab.ui.control.Label
        p4EditField2                    matlab.ui.control.NumericEditField
        p_1Label_17                     matlab.ui.control.Label
        p3EditField2                    matlab.ui.control.NumericEditField
        p_1Label_16                     matlab.ui.control.Label
        p2EditField2                    matlab.ui.control.NumericEditField
        p_1Label_15                     matlab.ui.control.Label
        p1EditField2                    matlab.ui.control.NumericEditField
        p_1Label_14                     matlab.ui.control.Label
        IkssImage                       matlab.ui.control.Image
        Ikslow2Image                    matlab.ui.control.Image
        IncludeKss                      matlab.ui.control.CheckBox
        Ikslow1Image                    matlab.ui.control.Image
        IncludeKslow2                   matlab.ui.control.CheckBox
        IncludeKslow1                   matlab.ui.control.CheckBox
        LabelKss                        matlab.ui.control.Label
        LabelKslow2                     matlab.ui.control.Label
        LabelKslow1                     matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        ExperimentalDataPatchclampCurrentRecordingsPanel  matlab.ui.container.Panel
        FileEditField_2                 matlab.ui.control.EditField
        FileEditField_2Label            matlab.ui.control.Label
        DatauploadButton                matlab.ui.control.Button
        UIAxesExpData                   matlab.ui.control.UIAxes
        ProtocolPanel                   matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        ClampingvoltagemaxmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltagemaxmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagestepmVEditField  matlab.ui.control.NumericEditField
        ClampingvoltagestepmVEditFieldLabel  matlab.ui.control.Label
        NernstpotentialmVEditField      matlab.ui.control.NumericEditField
        NernstpotentialmVEditFieldLabel  matlab.ui.control.Label
        ClampingtimemsEditField         matlab.ui.control.NumericEditField
        ClampingtimemsEditFieldLabel    matlab.ui.control.Label
        ClampingvoltageminmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltageminmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField     matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingtimemsEditField          matlab.ui.control.NumericEditField
        HoldingtimemsEditFieldLabel     matlab.ui.control.Label
        UIAxesProtocol                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        kvktof_tiles
        kvkslow1_tiles
        kvkslow2_tiles
        kvkss_tiles

        % time space
        t
        hold_t
        pulse_t
        tt

        % voltage space
        volts
        num_volts
        volt_now

        % experimental data
        exp_t
        exp_yksum_set
        exp_volts
        exp_num_volts
        exp_idx

        % currents
        yksum_set
        yktof_set
        ykslow1_set
        ykslow2_set
        ykss_set
        ykur_set
        yk1_set

        % current flags
        yktof_idx
        ykslow1_idx
        ykslow2_idx
        ykss_idx
        ykur_idx
        yk1_idx
        
        % current parameters
        pktof
        pkslow1
        pkslow2
        pkss
        pkur
        pk1
        
        % parameter bounds
        lb_ktof
        lb_kslow1
        lb_kslow2
        lb_kss
        lb_kur
        lb_k1
        ub_ktof
        ub_kslow1
        ub_kslow2
        ub_kss
        ub_kur
        ub_k1

        % external apps
        NanWarnApp
        VoltWarnApp
    end
    
    methods (Access = private)
        function [t, hold_t, pulse_t] = gen_time_space(app)
            % generate time information from given protocol
            hold_len = app.HoldingtimemsEditField.Value;
            time_len = hold_len + app.ClampingtimemsEditField.Value;
            
            hold_t = 0:hold_len;
            pulse_t = (hold_len+1):time_len;
            pulse_t_shift = pulse_t - pulse_t(1);
            
            t = [hold_t, pulse_t];
            pulse_t = pulse_t_shift;
        end

        function [volts, num_volts] = gen_volt_space(app)
            % generate voltage information from given protocol
            minv = app.ClampingvoltageminmVEditField.Value;
            stepv = app.ClampingvoltagestepmVEditField.Value;
            maxv = app.ClampingvoltagemaxmVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function [y,k] = gen_iktof(app, v)
            [y,k] = iktof(app.pktof, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);                
        end
        
        function [y,k] = gen_ikslow1(app, v)
            [y,k] = ikslow1(app.pkslow1, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);
        end
        
        function [y,k] = gen_ikslow2(app, v)
            [y,k] = ikslow2(app.pkslow2, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);
        end
        
        function [y,k] = gen_ikss(app, v)
            [y,k] = ikss(app.pkss, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);                
        end
      
        function y = gen_ik1(app)
            y = zeros(length(app.t), 1);
        end

        function y = gen_ikur(app)
            y = zeros(length(app.t), 1);
        end

        function yset = gen_currents_over_volts(app, current_flag, current_generator)
            % wrapper function looping over voltages
            if isequal(current_flag,1)
                yset = NaN(length(app.t), app.num_volts);
                for i=1:app.num_volts
                    y = current_generator(app, app.volts(i));
                    if any(isnan(y))    
                        % warning message for NaN
                        app.NanWarnApp = warn_message_nan();
                        break
                    else
                        yset(:,i) = y;
                    end
                end
            else
                yset = zeros(length(app.t), app.num_volts);
            end
        end

        function [yset,kset] = gen_kinetic_over_volts(app,current_generator)
            % wrapper function looping over voltages
            yset = NaN(length(app.t),app.num_volts);
            kset = [];
            for i=1:app.num_volts
                [y,k] = current_generator(app,app.volts(i));
                if any(isnan(y))
                    % warning message for NaN
                    app.NanWarnApp = warn_message_nan();
                    break
                else
                    yset(:,i) = y;
                    kset(:,i) = k;
                end
            end
        end
    
        function update_sum_current(app)
            app.yksum_set = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.yktof_set(:,i) + app.ykslow1_set(:,i) + ...
                    app.ykslow2_set(:,i) + app.ykss_set(:,i) + app.ykur_set(:,i) + app.yk1_set(:,i);
            end
        end

        function plot_protocol(app)
            yhold = ones(length(app.hold_t), 1)*app.HoldingpotentialmVEditField.Value;
            ypulse = ones(length(app.pulse_t), 1)*app.volts(1);
            y = [yhold; ypulse];
            plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesProtocol, "on")
            for i=2:app.num_volts
                ypulse = ones(length(app.pulse_t), 1)*app.volts(i);
                y = [yhold; ypulse];
                plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesProtocol, "off")            
        end
    
        function plot_currents(app)
            volt_idx = app.volt_now;

            % figure 1: individual K+ currents
            % find first current to be plotted
            current_idx_set = [app.yktof_idx, app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx, app.yk1_idx];
            active_current_idx = find(current_idx_set == 1);

            plot_list = gobjects(length(active_current_idx),1);
            legend_list = strings(length(active_current_idx),1);

            % first plot before hold on
            if isempty(active_current_idx)
                cla(app.UIAxesModelIndv)
                cla(app.UIAxesModelSum)
            else
                switch active_current_idx(1)
                case 1
                    plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.yktof_set(:,volt_idx));
                    legend_list(1) = "I_{Ktof}";
                case 2
                    plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx));
                    legend_list(1) = "I_{Kslow1}";
                case 3
                    plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx));
                    legend_list(1) = "I_{Kslow2}";
                case 4
                    plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx));
                    legend_list(1) = "I_{Kss}";
                case 5
                    plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.yk1_set(:,volt_idx));
                    legend_list(1) = "I_{K1}";
                end
                
                hold(app.UIAxesModelIndv, "on")
                for i = 2:length(active_current_idx)
                    switch active_current_idx(i)
                    case 1
                        plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.yktof_set(:,volt_idx));
                        legend_list(i) = "I_{Ktof}";
                    case 2
                        plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx));
                        legend_list(i) = "I_{Kslow1}";
                    case 3
                        plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx));
                        legend_list(i) = "I_{Kslow2}";
                    case 4
                        plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx));
                        legend_list(i) = "I_{Kss}";
                    case 5
                        plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.yk1_set(:,volt_idx));
                        legend_list(i) = "I_{K1}";
                    end
                end
                hold(app.UIAxesModelIndv, "off")
                axis(app.UIAxesModelIndv, "tight")
                legend(app.UIAxesModelIndv, legend_list, "Location","northeast")                
            end
            
            % figure 2: iksum
            if app.exp_idx == true
                plot(app.UIAxesModelSum, app.exp_t, app.exp_yksum_set(:,volt_idx), "LineWidth",2)
                hold(app.UIAxesModelSum, "on")
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                hold(app.UIAxesModelSum, "off")
                axis(app.UIAxesModelSum, "tight")
                legend(app.UIAxesModelSum, "Experimental Data", "Model Prediction", "Location","best")
            else
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesModelSum, "tight")
                legend(app.UIAxesModelSum, "Model Prediction", "Location","best")                
            end
        end
        
        function scaledp = scale_param(app, unitp, model_struct)
            num_currents = length(model_struct);
            [num_pt, num_var] = size(unitp);
            scaledp = NaN(num_pt, num_var);

            for i = 1:num_pt
                row = NaN(1, num_var);
                for j = 1:num_currents
                    switch model_struct(j).name
                    case 'ikto'
                        lb = app.lb_kto(model_struct(j).idx1);
                        ub = app.ub_kto(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikslow1'
                        lb = app.lb_kslow1(model_struct(j).idx1);
                        ub = app.ub_kslow1(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikslow2'
                        lb = app.lb_kslow2(model_struct(j).idx1);
                        ub = app.ub_kslow2(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikss'
                        lb = app.lb_kss(model_struct(j).idx1);
                        ub = app.ub_kss(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ikur'
                        lb = app.lb_kur(model_struct(j).idx1);
                        ub = app.ub_kur(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    case 'ik1'
                        lb = app.lb_k1(model_struct(j).idx1);
                        ub = app.ub_k1(model_struct(j).idx1);
                        row(model_struct(j).idx2) = unitp(i, model_struct(j).idx2).*(ub-lb) + lb;
                    end
                end
                scaledp(i, :) = row;
            end
        end
        
        function update_pktof(app, sol)
            app.pktof = sol;
            app.p1EditField.Value = sol(1);
            app.p2EditField.Value = sol(2);
            app.p3EditField.Value = sol(3);
            app.p4EditField.Value = sol(4);
            app.p5EditField.Value = sol(5);
            app.p6EditField.Value = sol(6);
            app.p7EditField.Value = sol(7);
            app.p8EditField.Value = sol(8);
            app.p9EditField.Value = sol(9);
            app.p10EditField.Value = sol(10);
            app.p11EditField.Value = sol(11);
            app.p12EditField.Value = sol(12);
            app.GKtofEditField.Value = sol(13);
        end

        function update_pkslow1(app, sol)
            app.pkslow1 = sol;
            app.pkslow2([1:7, 9]) = sol([1:7, 9]);
            app.pkss(1:3) = sol([1,3,4]);
            app.p1EditField2.Value = sol(1);
            app.p2EditField2.Value = sol(2);
            app.p3EditField2.Value = sol(3);
            app.p4EditField2.Value = sol(4);
            app.p5EditField2.Value = sol(5);
            app.p6EditField2.Value = sol(6);
            app.p7EditField2.Value = sol(7);
            app.p8EditField2.Value = sol(8);
            app.p9EditField2.Value = sol(9);
            app.p10EditField2.Value = sol(10);
            app.GKslow1EditField.Value = sol(11);
        end

        function update_pkslow2(app, sol)
            app.pkslow2([8,10,11]) = sol;
            app.p1EditField3.Value = sol(1);
            app.p2EditField3.Value = sol(2);
            app.GKslow2EditField.Value = sol(3);
        end

        function update_pkss(app, sol)
            app.pkss(4:7) = sol;
            app.p1EditField4.Value = sol(1);
            app.p2EditField4.Value = sol(2);
            app.p3EditField4.Value = sol(3);
            app.GKssEditField.Value = sol(4);
        end        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.kvktof_tiles = tiledlayout(app.KvKtof,4,2);
            app.kvkslow1_tiles = tiledlayout(app.KvKslow1,2,2);
            app.kvkslow2_tiles = tiledlayout(app.KvKslow2,1,2);
            app.kvkss_tiles = tiledlayout(app.KvKss,1,2);
            
            % generate time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt = cell(1,3);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;

            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.VoltageDropDown.Items = volt_dd_items;

            % plot default experimental protocol
            plot_protocol(app);

            % check currents to be included
            app.yktof_idx = app.IncludeKtof.Value;
            app.ykslow1_idx = app.IncludeKslow1.Value;
            app.ykslow2_idx = app.IncludeKslow2.Value;
            app.ykss_idx = app.IncludeKss.Value;
            app.ykur_idx = 0;
            app.yk1_idx = 0;
            
            % assign default ktof parameters
            app.pktof = NaN(13,1);
            app.pktof(1) = app.p1EditField.Value;
            app.pktof(2) = app.p2EditField.Value;
            app.pktof(3) = app.p3EditField.Value;
            app.pktof(4) = app.p4EditField.Value;
            app.pktof(5) = app.p5EditField.Value;
            app.pktof(6) = app.p6EditField.Value;
            app.pktof(7) = app.p7EditField.Value;
            app.pktof(8) = app.p8EditField.Value;
            app.pktof(9) = app.p9EditField.Value;
            app.pktof(10) = app.p10EditField.Value;
            app.pktof(11) = app.p11EditField.Value;
            app.pktof(12) = app.p12EditField.Value;
            app.pktof(13) = app.GKtofEditField.Value;

            % assign default kslow1 parameters
            app.pkslow1 = NaN(11,1);
            app.pkslow1(1) = app.p1EditField2.Value;
            app.pkslow1(2) = app.p2EditField2.Value;
            app.pkslow1(3) = app.p3EditField2.Value;
            app.pkslow1(4) = app.p4EditField2.Value;
            app.pkslow1(5) = app.p5EditField2.Value;
            app.pkslow1(6) = app.p6EditField2.Value;
            app.pkslow1(7) = app.p7EditField2.Value;
            app.pkslow1(8) = app.p8EditField2.Value;
            app.pkslow1(9) = app.p9EditField2.Value;
            app.pkslow1(10) = app.p10EditField2.Value;
            app.pkslow1(11) = app.GKslow1EditField.Value;
            
            % assign default kslow2 parameters
            app.pkslow2 = NaN(11,1);
            app.pkslow2(1) = app.p1EditField2.Value;
            app.pkslow2(2) = app.p2EditField2.Value;
            app.pkslow2(3) = app.p3EditField2.Value;
            app.pkslow2(4) = app.p4EditField2.Value;
            app.pkslow2(5) = app.p5EditField2.Value;
            app.pkslow2(6) = app.p6EditField2.Value;
            app.pkslow2(7) = app.p7EditField2.Value;
            app.pkslow2(8) = app.p1EditField3.Value;
            app.pkslow2(9) = app.p9EditField2.Value;
            app.pkslow2(10) = app.p2EditField3.Value;
            app.pkslow2(11) = app.GKslow2EditField.Value;

            % assign default kss parameters
            app.pkss = NaN(7,1);
            app.pkss(1) = app.p1EditField2.Value;
            app.pkss(2) = app.p3EditField2.Value;
            app.pkss(3) = app.p4EditField2.Value;
            app.pkss(4) = app.p1EditField4.Value;
            app.pkss(5) = app.p2EditField4.Value;
            app.pkss(6) = app.p3EditField4.Value;
            app.pkss(7) = app.GKssEditField.Value;
            
            app.pkur = 0;
            app.pk1 = 0;

            % generate individual currents
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            app.ykur_set = gen_currents_over_volts(app, app.ykur_idx, @gen_ikur);
            app.yk1_set = gen_currents_over_volts(app, app.yk1_idx, @gen_ik1);

            % calculate iksum
            update_sum_current(app);
            
            % plot currents
            app.volt_now = 1;
            plot_currents(app);

            % parameter bounds
            app.lb_ktof = NaN(1,length(app.pktof));
            app.lb_ktof([1:4, 13]) = [-70, -70, eps, 1, eps];
            app.lb_ktof(5:12) = app.pktof(5:12)*0.05;
            app.lb_kslow1 = [-70, -70, -70, 1, 1, eps, eps, eps, eps, 50+eps, eps];
            app.lb_kslow2 = [eps, 5000, eps];
            app.lb_kss = [eps, eps, eps, eps];
            app.lb_kur = [eps, 500, eps];
            app.lb_k1 = [eps, -20, eps, -30, eps, eps, eps, eps, eps, eps];
            
            app.ub_ktof = NaN(1,length(app.pktof));
            app.ub_ktof([1:4, 13]) = [70, 40, 40, 30, 1];
            app.ub_ktof(5:12) = app.pktof(5:12)*1.95;
            app.ub_kslow1 = [50, 50, 50, 20, 20, 1, 30, 50, 20, 2000, 1];
            app.ub_kslow2 = [5000, 10000, 1];
            app.ub_kss = [1, 2000, 100, 1];
            app.ub_kur = [500, 2000, 1];
            app.ub_k1 = [120, 30, 1000, 30, 3, 1, 2, 0.25, 0.25, 1];         
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % update time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;
            
            % update voltages
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.VoltageDropDown.Items = volt_dd_items;

            % update protocol figure
            plot_protocol(app);

            % update currents
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            app.ykur_set = zeros(length(app.t),app.num_volts);
            app.yk1_set = zeros(length(app.t),app.num_volts);
            update_sum_current(app);
            
            % reset experimental data
            if (app.exp_idx == true)
                app.exp_idx = false;
                app.FileEditField_2.Value = "";
                cla(app.UIAxesExpData)
                app.exp_idx = false;
            end

            % update currents figure
            app.volt_now = 1;
            app.VoltageDropDown.Value = volt_dd_items(1);
            plot_currents(app);
        end

        % Button pushed function: DatauploadButton
        function DatauploadButtonPushed(app, event)
            [file,path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
            
            % when close file selection window without selecting file
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % display file name
            app.FileEditField_2.Value = file;

            % load experimental data
            exp_data = table2array(readtable(fullfile(path, file)));
            app.exp_t = exp_data(:,1);
            app.exp_yksum_set = exp_data(:,2:end);
            
            % meta info
            app.exp_idx = true;
            [~, app.exp_num_volts] = size(app.exp_yksum_set);
            
            % estimate voltages of experimental data
            min_volt = app.ClampingvoltageminmVEditField.Value;
            volt_step = app.ClampingvoltagestepmVEditField.Value;
            app.exp_volts = min_volt:volt_step:(min_volt + (app.exp_num_volts-1)*volt_step);
            
            % check protocols match (given vs experimental)
            if ~isequal(app.exp_num_volts, app.num_volts)
                app.VoltWarnApp = warn_message_exp_volt();
                app.exp_idx = false;
                app.FileEditField_2.Value = "";
                return
            elseif ~isequal(app.exp_volts, app.volts)
                app.VoltWarnApp = warn_message_exp_volt();
                app.exp_idx = false;
                app.FileEditField_2.Value = "";
                return
            end
            
            % estimate time points
            [~, ideal_hold_idx] = min(abs(app.exp_t-app.HoldingtimemsEditField.Value)); 
            [~, ideal_end_idx] = min(abs(app.exp_t-app.ClampingtimemsEditField.Value));
            app.t = app.exp_t(1:ideal_end_idx);
            app.tt{1} = app.t;

            app.hold_t = app.t(1:ideal_hold_idx);
            app.tt{2} = app.hold_t;
            
            exp_pulse_t = app.t(ideal_hold_idx+1:end);
            app.pulse_t = exp_pulse_t - exp_pulse_t(1);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;
            
            % update currents
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            app.ykur_set = zeros(length(app.t),app.num_volts);
            app.yk1_set = zeros(length(app.t),app.num_volts);
            update_sum_current(app);
            plot_currents(app);

            % visualize experimental data
            plot(app.UIAxesExpData, app.exp_t, app.exp_yksum_set(:,1))
            hold(app.UIAxesExpData, "on")
            for i=2:app.exp_num_volts
                plot(app.UIAxesExpData, app.exp_t, app.exp_yksum_set(:,i))
            end
            hold(app.UIAxesExpData, "off")
            axis(app.UIAxesExpData, "tight")
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            if app.exp_idx == 0
                error('No experimental data')
            end

            % progress bar %
            % Store original button text
            orig_button_text = app.RunCalibrationButton.Text;

            % When the function ends, return the original button state
            cleanup = onCleanup(@()set(app.RunCalibrationButton, 'Text',orig_button_text, 'Icon',''));

            % Change button name to "Processing"
            app.RunCalibrationButton.Text = 'Processing...';
            
            % Put text on top of icon
            app.RunCalibrationButton.IconAlignment = 'bottom';

            % Create waitbar with same color as button
            wbar = permute(repmat(app.RunCalibrationButton.BackgroundColor,15,1,1000),[1,3,2]);

            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;

            % Load the empty waitbar to the button
            app.RunCalibrationButton.Icon = wbar;
            pause(0.1)

            % current model structure %
            current_idx_set = [app.yktof_idx, app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx, app.ykur_idx, app.yk1_idx];
            current_names = {'ikto', 'ikslow1', 'ikslow2', 'ikss', 'ikur', 'ik1'};
            delete_idx = [];
            for i = 1:length(current_idx_set)
                if current_idx_set(i) == 0
                    delete_idx = [delete_idx,i];
                end
            end
            current_names(delete_idx) = [];

            % tunning index in individual current models
            num_currents = length(current_names);
            tune_idx1_kto = [1, 2, 6, 10, 13, 14, 15, 16, 17];
            tune_idx1_kslow1 = [1, 2, 3, 4, 5, 8, 9, 11, 12, 13];
            tune_idx1_kslow2 = [1, 3];
            tune_idx1_kss = [3, 4];
            tune_idx1_kur = [1, 3];
            tune_idx1_k1 = [1, 3, 5, 7];
            
            idx_info1 = cell(1, num_currents);
            for i = 1:num_currents
                switch current_names{i}
                case 'ikto'
                    idx_info1{i} = tune_idx1_kto;
                case 'ikslow1'
                    idx_info1{i} = tune_idx1_kslow1;
                case 'ikslow2'
                    idx_info1{i} = tune_idx1_kslow2;
                case 'ikss'
                    idx_info1{i} = tune_idx1_kss;
                case 'ikur'
                    idx_info1{i} = tune_idx1_kur;
                case 'ik1'
                    idx_info1{i} = tune_idx1_k1;
                end
            end

            % tunning index in decision variable, p
            idx_info2 = cell(1, num_currents);
            cul_idx_len = 0;
            for i = 1: num_currents
                cul_idx_len_old = cul_idx_len;
                cul_idx_len = cul_idx_len + length(idx_info1{i});
                idx_info2{i} = (1+cul_idx_len_old):(cul_idx_len);
            end

            % create model structure
            model_info = [current_names; idx_info1; idx_info2];
            field_names = {'name', 'idx1', 'idx2'};
            model_struct = cell2struct(model_info, field_names, 1);

            % default values
            kto_default = [33, 15.5, 20, 16, 8, 7, 0.03577, 0.06237, 0.18064, 0.3956, 0.000152, 0.067083, 0.00095, 0.051335, 0.2087704319, 0.14067, 0.387];
            kslow1_default = [22.5, 45.2, 40.0, 7.7, 5.7, 6.1, 0.0629, 2.058, 803.0, 18.0, 0.9214774521, 0.05766, 0.07496];
            kslow2_default = [5334, 4912, 0.05766];
            kss_default = [0.0862, 1235.5, 13.17, 0.0428];
            kur_default = [270, 1050, 0];
            k1_default = [59.215, 5.476, 594.31, 4.753, 1.02, 0.2385, 0.8, 0.08032, 0.06175, 0.5143];
            
            % initial point and bounds for calibration parameters
            p0 = NaN(1, cul_idx_len);
            lb = NaN(1, cul_idx_len);
            ub = NaN(1, cul_idx_len);
            for i = 1:num_currents
                switch current_names{i}
                case 'ikto'
                    p0(idx_info2{i}) = kto_default(tune_idx1_kto);
                    lb(idx_info2{i}) = app.lb_kto(tune_idx1_kto);
                    ub(idx_info2{i}) = app.ub_kto(tune_idx1_kto);
                case 'ikslow1'
                    p0(idx_info2{i}) = kslow1_default(tune_idx1_kslow1);
                    lb(idx_info2{i}) = app.lb_kslow1(tune_idx1_kslow1);
                    ub(idx_info2{i}) = app.ub_kslow1(tune_idx1_kslow1);
                case 'ikslow2'
                    p0(idx_info2{i}) = kslow2_default(tune_idx1_kslow2);
                    lb(idx_info2{i}) = app.lb_kslow2(tune_idx1_kslow2);
                    ub(idx_info2{i}) = app.ub_kslow2(tune_idx1_kslow2);
                case 'ikss'
                    p0(idx_info2{i}) = kss_default(tune_idx1_kss);
                    lb(idx_info2{i}) = app.lb_kss(tune_idx1_kss);
                    ub(idx_info2{i}) = app.ub_kss(tune_idx1_kss);
                case 'ikur'
                    p0(idx_info2{i}) = kur_default(tune_idx1_kur);
                    lb(idx_info2{i}) = app.lb_kur(tune_idx1_kur);
                    ub(idx_info2{i}) = app.ub_kur(tune_idx1_kur);
                case 'ik1'
                    p0(idx_info2{i}) = k1_default(tune_idx1_kur);
                    lb(idx_info2{i}) = app.lb_k1(tune_idx1_k1);
                    ub(idx_info2{i}) = app.ub_k1(tune_idx1_k1);
                end    
            end
    
            % protocol info
            volt_space = cell(1,3);
            volt_space{1} = app.HoldingpotentialmVEditField.Value;
            volt_space{2} = app.volts;
            volt_space{3} = app.NernstpotentialmVEditField.Value;

            time_space = cell(1,4);
            [~, ideal_hold_idx] = min(abs(app.exp_t-app.HoldingtimemsEditField.Value)); % estimate time points
            [~, ideal_end_idx] = min(abs(app.exp_t-app.ClampingtimemsEditField.Value));
            app.t = app.exp_t(1:ideal_end_idx);
            time_space{1} = app.t;
            app.hold_t = app.t(1:ideal_hold_idx);
            time_space{2} = app.hold_t;
            app.pulse_t = app.t(ideal_hold_idx+1:end);
            pulse_t_adj = app.pulse_t-app.pulse_t(1);
            time_space{3} = pulse_t_adj;
            time_space{4} = ideal_hold_idx;
            time_space{5} = ideal_end_idx;
            app.tt = time_space;

            % optimization options
            options = optimoptions(@fmincon, ...
                'Algorithm','interior-point', 'Display','off',...
                'MaxFunctionEvaluations',1e+3, ...
                'SpecifyObjectiveGradient',false);

            % optimization constraints 
            A = [];
            b = [];
            Aeq = [];
            beq = [];
            nonlcon = [];

            % objective function
            obj_fun = @(p) obj_rmse(p, @kcurrent_model1, model_struct, volt_space, time_space, app.exp_yksum_set(1:ideal_end_idx,:));
%             obj_fun = @(p) obj_rmse_grad(p, model_struct, volt_space, time_space, app.exp_yksum_set(1:ideal_end_idx,:));

            % run optimization
            rmse_list = zeros(app.IterationsEditField.Value, 1);
            sol_list = cell(app.IterationsEditField.Value, 1);

            % first run with p0
            [sol, fval] = fmincon(obj_fun, p0, A, b, Aeq, beq, lb, ub, nonlcon, options);
            rmse_list(1) = fval;
            sol_list{1} = sol;

            % random intialization
            running_p0 = lhsdesign(app.IterationsEditField.Value, cul_idx_len);
            running_p0 = scale_param(app, running_p0, model_struct);
            for i = 2:app.IterationsEditField.Value
                try
                   [sol,fval] = fmincon(obj_fun, running_p0(i,:), A, b, Aeq, beq, lb, ub, nonlcon, options);
                   rmse_list(i) = fval;
                   sol_list{i} = sol;
                catch
                   rmse_list(i) = 1e+3;
                end
                % update progress bar
                current_prog = min(round((size(wbar,2)-2)*(i/app.IterationsEditField.Value)), size(wbar,2)-2); 
                app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 1) = 0.25391; % (royalblue)
                app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 2) = 0.41016;
                app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 3) = 0.87891;
                pause(0.1)            
            end
            [~, best_fit_idx] = min(rmse_list);
            best_sol = sol_list{best_fit_idx};

            for i = 1:num_currents
                switch current_names{i}
                case 'ikto'
                    sol_kto = kto_default;
                    sol_kto(tune_idx1_kto) = best_sol(idx_info2{i});
                    update_pkto(app,sol_kto);
                case 'ikslow1'
                    sol_kslow1 = kslow1_default;
                    sol_kslow1(tune_idx1_kslow1) = best_sol(idx_info2{i});
                    update_pkslow1(app,sol_kslow1);
                case 'ikslow2'
                    sol_kslow2 = kslow2_default;
                    sol_kslow2(tune_idx1_kslow2) = best_sol(idx_info2{i});
                    update_pkslow2(app,sol_kslow2);
                case 'ikss'
                    sol_kss = kss_default;
                    sol_kss(tune_idx1_kss) = best_sol(idx_info2{i});
                    update_pkss(app,sol_kss);
                case 'ikur'
                    sol_kur = kur_default;
                    sol_kur(tune_idx1_kur) = best_sol(idx_info2{i});
                case 'ik1'
                    sol_k1 = k1_default;
                    sol_k1(tune_idx1_k1) = best_sol(idx_info2{i});
                end
            end
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            app.ykur_set = zeros(length(app.t),app.num_volts);
            app.yk1_set = zeros(length(app.t),app.num_volts);
            update_sum_current(app);
            plot_currents(app,app.volt_now);
        end

        % Button pushed function: ModelParametersImportButton
        function ModelParametersImportButtonPushed(app, event)
            [file,path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
            
            % when close file selection window without selecting file
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % display file name
            app.FileEditField.Value = file;
            
            % display RMSE if file name has it
            file_split = strsplit(file,"_");
            if length(file_split) > 1
                rmse = file_split(2);
                app.RMSEEditField.Value = rmse;
            end
            
            % import parameters saved in Excel file format
            param = readtable(fullfile(path, file));
            var_names = param.Properties.VariableNames;
            var_names = lower(var_names);

            if any(contains(var_names, 'ikto'))
                if isequal(app.yktof_idx,1)
                    sol = param.ikto;
                    sol = sol(~isnan(sol));
                    update_pktof(app, sol);
                    [app.yktof_set,kvktof_set] = gen_kinetic_over_volts(app, @gen_iktof);

                    % visualize kinetic variables
                    app.kvktof_tiles = tiledlayout(app.KvKtof,4,2);
                    xlabel(app.kvktof_tiles,"Voltage (mV)", "FontSize",12)
                    ax1 = nexttile(app.kvktof_tiles);
                    plot(ax1,app.volts,kvktof_set(5,:),"-o", "Color","black")
                    ylabel(ax1,"\alpha_{a}", "FontSize",12)

                    ax2 = nexttile(app.kvktof_tiles);
                    plot(ax2,app.volts,kvktof_set(6,:),"-o", "Color","black")
                    ylabel(ax2,"\beta{a}", "FontSize",12)
    
                    ax3 = nexttile(app.kvktof_tiles);
                    plot(ax3,app.volts,kvktof_set(1,:),"-or")
                    ylim(ax3,[0,1])
                    ylabel(ax3,"a_{ss}", "FontSize",12)

                    ax4 = nexttile(app.kvktof_tiles);
                    plot(ax4,app.volts,kvktof_set(3,:),"-or")
                    ylabel(ax4,"\tau_{a}", "FontSize",12)

                    ax5 = nexttile(app.kvktof_tiles);
                    plot(ax5,app.volts,kvktof_set(7,:),"-o", "Color","black")
                    ylabel(ax5,"\alpha_{i}", "FontSize",12)

                    ax6 = nexttile(app.kvktof_tiles);
                    plot(ax6,app.volts,kvktof_set(8,:),"-o", "Color","black")
                    ylabel(ax6,"\beta_{i}", "FontSize",12)

                    ax7 = nexttile(app.kvktof_tiles);
                    plot(ax7,app.volts,kvktof_set(2,:),"-ob")
                    ylim(ax7,[0,1])
                    ylabel(ax7,"i_{ss}", "FontSize",12)

                    ax8 = nexttile(app.kvktof_tiles);
                    plot(ax8,app.volts,kvktof_set(4,:),"-ob")
                    ylabel(ax8,"\tau_{i}", "FontSize",12)
                end                
            end
            
            if any(contains(var_names, 'ikslow1'))
                if isequal(app.ykslow1_idx,1)
                    sol = param.ikslow1;
                    sol = sol(~isnan(sol));
                    update_pkslow1(app, sol);                    
                    [app.ykslow1_set,kvkslow1_set] = gen_kinetic_over_volts(app, @gen_ikslow1);

                    % visualize kinetic variables
                    app.kvkslow1_tiles = tiledlayout(app.KvKslow1,2,2);
                    xlabel(app.kvkslow1_tiles,"Voltage (mV)", "FontSize",12)
                    ax1 = nexttile(app.kvkslow1_tiles);
                    plot(ax1,app.volts,kvkslow1_set(1,:),"-or")
                    ylabel(ax1,"a_{ss}", "FontSize",12)

                    ax2 = nexttile(app.kvkslow1_tiles);
                    plot(ax2,app.volts,kvkslow1_set(3,:),"-or")
                    ylabel(ax2,"\tau_{a}^{(1)}")

                    ax3 = nexttile(app.kvkslow1_tiles);
                    plot(ax3,app.volts,kvkslow1_set(2,:),"-ob")
                    ylabel(ax3,"i_{ss}")

                    ax4 = nexttile(app.kvkslow1_tiles);
                    plot(ax4,app.volts,kvkslow1_set(4,:),"-ob")
                    ylabel(ax4,"\tau_{i}^{(1)}")
                end
            end

            if any(contains(var_names, 'ikslow2'))
                if isequal(app.ykslow2_idx,1)
                    sol = param.ikslow2;
                    sol = sol(~isnan(sol));
                    update_pkslow2(app, sol);
                    [app.ykslow2_set,kvkslow2_set] = gen_kinetic_over_volts(app, @gen_ikslow2);

                    % visualize kinetic variables
                    app.kvkslow2_tiles = tiledlayout(app.KvKslow2,1,2);
                    xlabel(app.kvkslow2_tiles,"Voltage (mV)", "FontSize",12)
                    ax1 = nexttile(app.kvkslow2_tiles);
                    plot(ax1,app.volts,kvkslow2_set(4,:),"-ob")
                    ylabel(ax1,"\tau_{i}^{(2)}")
                end
            end

            if any(contains(var_names, 'ikss'))
                if isequal(app.ykss_idx,1)
                    sol = param.ikss;
                    sol = sol(~isnan(sol));
                    update_pkss(app, sol);
                    [app.ykss_set,kvkss_set] = gen_kinetic_over_volts(app, @gen_ikss);

                    % visualize kinetic variables
                    app.kvkss_tiles = tiledlayout(app.KvKss,1,2);
                    xlabel(app.kvkss_tiles,"Voltage (mV)", "FontSize",12)
                    ax1 = nexttile(app.kvkss_tiles);
                    plot(ax1,app.volts,kvkss_set(2,:),"-or")
                    ylabel(ax1,"\tau_{a}^{(3)}")
                end
            end
            
            % update iksum and plot
            update_sum_current(app);
            plot_currents(app);
        end

        % Button pushed function: ModelParametersSaveButton
        function ModelParametersSaveButtonPushed(app, event)
            [file, path] = uiputfile("model_parameter.xlsx", "Save File Specifier");
            save_path = fullfile(path, file);
            
            % when closing window without action
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % header
            current_idx_set = [app.yktof_idx, app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx, app.yk1_idx, app.ykur_idx];
            current_names = {'ikto', 'ikslow1', 'ikslow2', 'ikss', 'ikur', 'ik1'};
            current_names = string(current_names(current_idx_set == 1));
            writematrix(current_names, save_path, "Sheet","Model Parameters", "Range","A1");
            
            % body
            max_param_len = 13;
            pmx = NaN(max_param_len,sum(current_idx_set));

            pkto_save = app.pktof;
            pkto_save = [pkto_save; NaN(max_param_len-length(pkto_save),1)];
            
            pkslow1_save = app.pkslow1;
            pkslow1_save = [pkslow1_save; NaN(max_param_len-length(pkslow1_save),1)];
            
            pkslow2_save = app.pkslow2([8,10,11]);
            pkslow2_save = [pkslow2_save; NaN(max_param_len-length(pkslow2_save),1)];

            pkss_save = app.pkss(4:7);
            pkss_save = [pkss_save; NaN(max_param_len-length(pkss_save),1)];
            
            pkur_save = app.pkur;
            pk1_save = app.pk1;

            for i = 1:sum(current_idx_set)
                switch current_names{i}
                case 'ikto'
                    pmx(:, i) = pkto_save;
                case 'ikslow1'
                    pmx(:, i) = pkslow1_save;
                case 'ikslow2'
                    pmx(:, i) = pkslow2_save;
                case 'ikss'
                    pmx(:, i) = pkss_save;
                case 'ikur'
                    pmx(:, i) = pkur_save;
                case 'ik1'
                    pmx(:, i) = pk1_save;
                end
            end
            writematrix(pmx, save_path, 'Sheet','Model Parameters', 'Range','A2');
        end

        % Value changed function: VoltageDropDown
        function VoltageDropDownValueChanged(app, event)
            value = app.VoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_now = find(app.volts == volt);
            plot_currents(app);            
        end

        % Value changed function: IncludeKtof
        function IncludeKtofValueChanged(app, event)
            value = app.IncludeKtof.Value;
            app.yktof_idx = value;
            
            if app.yktof_idx == 0
                app.yktof_set = zeros(length(app.t),app.num_volts);
            else
                app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            end
            update_sum_current(app);
            plot_currents(app);                 
        end

        % Value changed function: IncludeKslow1
        function IncludeKslow1ValueChanged(app, event)
            value = app.IncludeKslow1.Value;
            app.ykslow1_idx = value;

            if app.ykslow1_idx == 0
                app.ykslow1_set = zeros(length(app.t),app.num_volts);
            else
                app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            end
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: IncludeKslow2
        function IncludeKslow2ValueChanged(app, event)
            value = app.IncludeKslow2.Value;
            app.ykslow2_idx = value;

            if app.ykslow2_idx == 0
                app.ykslow2_set = zeros(length(app.t),app.num_volts);
            else
                app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            end
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: IncludeKss
        function IncludeKssValueChanged(app, event)
            value = app.IncludeKss.Value;
            app.ykss_idx = value;

            if app.ykss_idx == 0
                app.ykss_set = zeros(length(app.t),app.num_volts);
            else
                app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            end
            update_sum_current(app);
            plot_currents(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.NanWarnApp)
            delete(app.VoltWarnApp)
            delete(app)
        end

        % Value changed function: p1EditField
        function p1EditFieldValueChanged(app, event)
            value = app.p1EditField.Value;
            app.pktof(1) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p2EditField
        function p2EditFieldValueChanged(app, event)
            value = app.p2EditField.Value;
            app.pktof(2) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);            
        end

        % Value changed function: p3EditField
        function p3EditFieldValueChanged(app, event)
            value = app.p3EditField.Value;
            app.pktof(3) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);            
        end

        % Value changed function: p4EditField
        function p4EditFieldValueChanged(app, event)
            value = app.p4EditField.Value;
            app.pktof(4) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p5EditField
        function p5EditFieldValueChanged(app, event)
            value = app.p5EditField.Value;
            app.pktof(5) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p6EditField
        function p6EditFieldValueChanged(app, event)
            value = app.p6EditField.Value;
            app.pktof(6) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p7EditField
        function p7EditFieldValueChanged(app, event)
            value = app.p7EditField.Value;
            app.pktof(7) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p8EditField
        function p8EditFieldValueChanged(app, event)
            value = app.p8EditField.Value;
            app.pktof(8) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p9EditField
        function p9EditFieldValueChanged(app, event)
            value = app.p9EditField.Value;
            app.pktof(9) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p10EditField
        function p10EditFieldValueChanged(app, event)
            value = app.p10EditField.Value;
            app.pktof(10) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p11EditField
        function p11EditFieldValueChanged(app, event)
            value = app.p11EditField.Value;
            app.pktof(11) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p12EditField
        function p12EditFieldValueChanged(app, event)
            value = app.p12EditField.Value;
            app.pktof(12) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: GKtofEditField
        function GKtofEditFieldValueChanged(app, event)
            value = app.GKtofEditField.Value;
            app.pktof(13) = value;
            app.yktof_set = gen_currents_over_volts(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p1EditField2
        function p1EditField2ValueChanged(app, event)
            value = app.p1EditField2.Value;
            app.pkslow1(1) = value;
            app.pkslow2(1) = value;
            app.pkss(1) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);

            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p2EditField2
        function p2EditField2ValueChanged(app, event)
            value = app.p2EditField2.Value;
            app.pkslow1(2) = value;
            app.pkslow2(2) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            
            update_sum_current(app);
            plot_currents(app);            
        end

        % Value changed function: p3EditField2
        function p3EditField2ValueChanged(app, event)
            value = app.p3EditField2.Value;
            app.pkslow1(3) = value;
            app.pkslow2(3) = value;
            app.pkss(2) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);

            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p4EditField2
        function p4EditField2ValueChanged(app, event)
            value = app.p4EditField2.Value;
            app.pkslow1(4) = value;
            app.pkslow2(4) = value;
            app.pkss(3) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);

            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p5EditField2
        function p5EditField2ValueChanged(app, event)
            value = app.p5EditField2.Value;
            app.pkslow1(5) = value;
            app.pkslow2(5) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            
            update_sum_current(app);
            plot_currents(app);            
        end

        % Value changed function: p6EditField2
        function p6EditField2ValueChanged(app, event)
            value = app.p6EditField2.Value;
            app.pkslow1(6) = value;
            app.pkslow2(6) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            
            update_sum_current(app);
            plot_currents(app);            
        end

        % Value changed function: p7EditField2
        function p7EditField2ValueChanged(app, event)
            value = app.p7EditField2.Value;
            app.pkslow1(7) = value;
            app.pkslow2(7) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            
            update_sum_current(app);
            plot_currents(app);            
        end

        % Value changed function: p8EditField2
        function p8EditField2ValueChanged(app, event)
            value = app.p8EditField2.Value;
            app.pkslow1(8) = value;
            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p9EditField2
        function p9EditField2ValueChanged(app, event)
            value = app.p9EditField2.Value;
            app.pkslow1(9) = value;
            app.pkslow2(9) = value;

            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            
            update_sum_current(app);
            plot_currents(app);            
        end

        % Value changed function: p10EditField2
        function p10EditField2ValueChanged(app, event)
            value = app.p10EditField2.Value;
            app.pkslow1(10) = value;
            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: GKslow1EditField
        function GKslow1EditFieldValueChanged(app, event)
            value = app.GKslow1EditField.Value;
            app.pkslow1(11) = value;
            app.ykslow1_set = gen_currents_over_volts(app, app.ykslow1_idx, @gen_ikslow1);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p1EditField3
        function p1EditField3ValueChanged(app, event)
            value = app.p1EditField3.Value;
            app.pkslow2(8) = value;
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p2EditField3
        function p2EditField3ValueChanged(app, event)
            value = app.p2EditField3.Value;
            app.pkslow2(10) = value;
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: GKslow2EditField
        function GKslow2EditFieldValueChanged(app, event)
            value = app.GKslow2EditField.Value;
            app.pkslow2(11) = value;
            app.ykslow2_set = gen_currents_over_volts(app, app.ykslow2_idx, @gen_ikslow2);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p1EditField4
        function p1EditField4ValueChanged(app, event)
            value = app.p1EditField4.Value;
            app.pkss(4) = value;
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p2EditField4
        function p2EditField4ValueChanged(app, event)
            value = app.p2EditField4.Value;
            app.pkss(5) = value;
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p3EditField4
        function p3EditField4ValueChanged(app, event)
            value = app.p3EditField4.Value;
            app.pkss(6) = value;
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: GKssEditField
        function GKssEditFieldValueChanged(app, event)
            value = app.GKssEditField.Value;
            app.pkss(7) = value;
            app.ykss_set = gen_currents_over_volts(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.ProtocolPanel = uipanel(app.LeftPanel);
            app.ProtocolPanel.Title = 'Experimental Protocol';
            app.UIAxesProtocol = uiaxes(app.ProtocolPanel);
            title(app.UIAxesProtocol, 'Protocol')
            xlabel(app.UIAxesProtocol, 'Time (ms)')
            ylabel(app.UIAxesProtocol, 'Clamping Voltage (mV)')
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingtimemsEditField.Value = 120;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampingvoltageminmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltageminmVEditFieldLabel.Text = 'Clamping voltage min (mV)';
            app.ClampingvoltageminmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltageminmVEditField.Value = -50;
            app.ClampingtimemsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingtimemsEditFieldLabel.Text = 'Clamping time (ms)';
            app.ClampingtimemsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingtimemsEditField.ValueDisplayFormat = '%.0f';
            app.ClampingtimemsEditField.Value = 4500;
            app.NernstpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.NernstpotentialmVEditFieldLabel.Text = 'Nernst potential (mV)';
            app.NernstpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.NernstpotentialmVEditField.Value = -91.1;
            app.ClampingvoltagestepmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltagestepmVEditFieldLabel.Text = 'Clamping voltage step (mV)';
            app.ClampingvoltagestepmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltagestepmVEditField.Value = 10;
            app.ClampingvoltagemaxmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltagemaxmVEditFieldLabel.Text = 'Clamping voltage max (mV)';
            app.ClampingvoltagemaxmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltagemaxmVEditField.Value = 50;
            app.UpdateButton = uibutton(app.ProtocolPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ExperimentalDataPatchclampCurrentRecordingsPanel = uipanel(app.LeftPanel);
            app.ExperimentalDataPatchclampCurrentRecordingsPanel.Title = 'Experimental Data (Patch-clamp Current Recordings)';
            app.UIAxesExpData = uiaxes(app.ExperimentalDataPatchclampCurrentRecordingsPanel);
            title(app.UIAxesExpData, 'Normalized I_{Ksum} Recordings')
            xlabel(app.UIAxesExpData, 'Time (ms)')
            ylabel(app.UIAxesExpData, 'K^{+} Current (pA/pF)')
            app.DatauploadButton = uibutton(app.ExperimentalDataPatchclampCurrentRecordingsPanel, 'push');
            app.DatauploadButton.ButtonPushedFcn = createCallbackFcn(app, @DatauploadButtonPushed, true);
            app.DatauploadButton.Text = 'Data upload';
            app.FileEditField_2Label = uilabel(app.ExperimentalDataPatchclampCurrentRecordingsPanel);
            app.FileEditField_2Label.Text = 'File ';
            app.FileEditField_2 = uieditfield(app.ExperimentalDataPatchclampCurrentRecordingsPanel, 'text');
            app.CenterPanel = uipanel(app.UIFigure);
            app.TabGroupComputerModel = uitabgroup(app.CenterPanel);
            app.Kv42Tab = uitab(app.TabGroupComputerModel);
            app.Kv42Tab.Title = 'Kv 4.2';
            app.LabelKtof = uilabel(app.Kv42Tab);
            app.LabelKtof.Text = 'IKto,f (Kv 4.2): Transient outward K+ current';
            app.IncludeKtof = uicheckbox(app.Kv42Tab);
            app.IncludeKtof.ValueChangedFcn = createCallbackFcn(app, @IncludeKtofValueChanged, true);
            app.IncludeKtof.Text = 'Include';
            app.IncludeKtof.Value = true;
            app.p_1Label = uilabel(app.Kv42Tab);
            app.p_1Label.Interpreter = 'latex';
            app.p_1Label.Text = '$p_{1}$';
            app.p1EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p1EditField.ValueDisplayFormat = '%11.5g';
            app.p1EditField.ValueChangedFcn = createCallbackFcn(app, @p1EditFieldValueChanged, true);
            app.p1EditField.Value = 33;
            app.p_1Label_2 = uilabel(app.Kv42Tab);
            app.p_1Label_2.Interpreter = 'latex';
            app.p_1Label_2.Text = '$p_{2}$';
            app.p2EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p2EditField.ValueDisplayFormat = '%11.5g';
            app.p2EditField.ValueChangedFcn = createCallbackFcn(app, @p2EditFieldValueChanged, true);
            app.p2EditField.Value = 15.5;
            app.p_1Label_3 = uilabel(app.Kv42Tab);
            app.p_1Label_3.Interpreter = 'latex';
            app.p_1Label_3.Text = '$p_{3}$';
            app.p3EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p3EditField.ValueDisplayFormat = '%11.5g';
            app.p3EditField.ValueChangedFcn = createCallbackFcn(app, @p3EditFieldValueChanged, true);
            app.p3EditField.Value = 20;
            app.p_1Label_4 = uilabel(app.Kv42Tab);
            app.p_1Label_4.Interpreter = 'latex';
            app.p_1Label_4.Text = '$p_{4}$';
            app.p4EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p4EditField.ValueDisplayFormat = '%11.5g';
            app.p4EditField.ValueChangedFcn = createCallbackFcn(app, @p4EditFieldValueChanged, true);
            app.p4EditField.Value = 7;
            app.p_1Label_5 = uilabel(app.Kv42Tab);
            app.p_1Label_5.Interpreter = 'latex';
            app.p_1Label_5.Text = '$p_{5}$';
            app.p5EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p5EditField.ValueDisplayFormat = '%11.5g';
            app.p5EditField.ValueChangedFcn = createCallbackFcn(app, @p5EditFieldValueChanged, true);
            app.p5EditField.Value = 0.03577;
            app.p_1Label_6 = uilabel(app.Kv42Tab);
            app.p_1Label_6.Interpreter = 'latex';
            app.p_1Label_6.Text = '$p_{6}$';
            app.p6EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p6EditField.ValueDisplayFormat = '%11.5g';
            app.p6EditField.ValueChangedFcn = createCallbackFcn(app, @p6EditFieldValueChanged, true);
            app.p6EditField.Value = 0.06237;
            app.p_1Label_7 = uilabel(app.Kv42Tab);
            app.p_1Label_7.Interpreter = 'latex';
            app.p_1Label_7.Text = '$p_{7}$';
            app.p7EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p7EditField.ValueDisplayFormat = '%11.5g';
            app.p7EditField.ValueChangedFcn = createCallbackFcn(app, @p7EditFieldValueChanged, true);
            app.p7EditField.Value = 0.18064;
            app.p_1Label_8 = uilabel(app.Kv42Tab);
            app.p_1Label_8.Interpreter = 'latex';
            app.p_1Label_8.Text = '$p_{8}$';
            app.p8EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p8EditField.ValueDisplayFormat = '%11.5g';
            app.p8EditField.ValueChangedFcn = createCallbackFcn(app, @p8EditFieldValueChanged, true);
            app.p8EditField.Value = 0.3956;
            app.p_1Label_9 = uilabel(app.Kv42Tab);
            app.p_1Label_9.Interpreter = 'latex';
            app.p_1Label_9.Text = '$p_{9}$';
            app.p9EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p9EditField.ValueDisplayFormat = '%11.5g';
            app.p9EditField.ValueChangedFcn = createCallbackFcn(app, @p9EditFieldValueChanged, true);
            app.p9EditField.Value = 0.000152;
            app.p_1Label_10 = uilabel(app.Kv42Tab);
            app.p_1Label_10.Interpreter = 'latex';
            app.p_1Label_10.Text = '$p_{10}$';
            app.p10EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p10EditField.ValueDisplayFormat = '%11.5g';
            app.p10EditField.ValueChangedFcn = createCallbackFcn(app, @p10EditFieldValueChanged, true);
            app.p10EditField.Value = 0.067083;
            app.p_1Label_11 = uilabel(app.Kv42Tab);
            app.p_1Label_11.Interpreter = 'latex';
            app.p_1Label_11.Text = '$p_{11}$';
            app.p11EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p11EditField.ValueDisplayFormat = '%11.5g';
            app.p11EditField.ValueChangedFcn = createCallbackFcn(app, @p11EditFieldValueChanged, true);
            app.p11EditField.Value = 0.00095;
            app.p_1Label_12 = uilabel(app.Kv42Tab);
            app.p_1Label_12.Interpreter = 'latex';
            app.p_1Label_12.Text = '$p_{12}$';
            app.p12EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p12EditField.ValueDisplayFormat = '%11.5g';
            app.p12EditField.ValueChangedFcn = createCallbackFcn(app, @p12EditFieldValueChanged, true);
            app.p12EditField.Value = 0.051335;
            app.p_1Label_13 = uilabel(app.Kv42Tab);
            app.p_1Label_13.Interpreter = 'latex';
            app.p_1Label_13.Text = '$G_{\mathrm{Kto,f}}$';
            app.GKtofEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.GKtofEditField.ValueDisplayFormat = '%11.5g';
            app.GKtofEditField.ValueChangedFcn = createCallbackFcn(app, @GKtofEditFieldValueChanged, true);
            app.GKtofEditField.Value = 0.3846;
            app.IktofImage = uiimage(app.Kv42Tab);
            app.IktofImage.ImageSource = 'iktof.png';
            app.Kv15Kv21K2PTab = uitab(app.TabGroupComputerModel);
            app.Kv15Kv21K2PTab.Title = 'Kv 1.5 / Kv 2.1 / K2P';
            app.LabelKslow1 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelKslow1.Text = 'IKslow1 (Kv 1.5): Rapidly activating, slowly inactivating rectifier K+ current 1';
            app.LabelKslow2 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelKslow2.Text = 'IKslow2 (Kv 2.1): Rapidly activating, slowly inactivating rectifier K+ current 2';
            app.LabelKss = uilabel(app.Kv15Kv21K2PTab);
            app.LabelKss.Text = 'IKss (K2P): Non-inactivating steady-state K+ current';
            app.IncludeKslow1 = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKslow1.ValueChangedFcn = createCallbackFcn(app, @IncludeKslow1ValueChanged, true);
            app.IncludeKslow1.Text = 'Include';
            app.IncludeKslow1.Value = true;
            app.IncludeKslow2 = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKslow2.ValueChangedFcn = createCallbackFcn(app, @IncludeKslow2ValueChanged, true);
            app.IncludeKslow2.Text = 'Include';
            app.IncludeKslow2.Value = true;
            app.Ikslow1Image = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow1Image.ImageSource = 'ikslow1.png';
            app.IncludeKss = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKss.ValueChangedFcn = createCallbackFcn(app, @IncludeKssValueChanged, true);
            app.IncludeKss.Text = 'Include';
            app.IncludeKss.Value = true;
            app.Ikslow2Image = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow2Image.ImageSource = 'ikslow2.png';
            app.IkssImage = uiimage(app.Kv15Kv21K2PTab);
            app.IkssImage.ImageSource = 'ikss.png';
            app.p_1Label_14 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_14.Interpreter = 'latex';
            app.p_1Label_14.Text = '$p_{1}$';
            app.p1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1EditField2.ValueChangedFcn = createCallbackFcn(app, @p1EditField2ValueChanged, true);
            app.p1EditField2.Value = 22.5;
            app.p_1Label_15 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_15.Interpreter = 'latex';
            app.p_1Label_15.Text = '$p_{2}$';
            app.p2EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2EditField2.ValueChangedFcn = createCallbackFcn(app, @p2EditField2ValueChanged, true);
            app.p2EditField2.Value = 45.2;
            app.p_1Label_16 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_16.Interpreter = 'latex';
            app.p_1Label_16.Text = '$p_{3}$';
            app.p3EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3EditField2.ValueChangedFcn = createCallbackFcn(app, @p3EditField2ValueChanged, true);
            app.p3EditField2.Value = 40;
            app.p_1Label_17 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_17.Interpreter = 'latex';
            app.p_1Label_17.Text = '$p_{4}$';
            app.p4EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p4EditField2.ValueChangedFcn = createCallbackFcn(app, @p4EditField2ValueChanged, true);
            app.p4EditField2.Value = 7.7;
            app.p_1Label_18 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_18.Interpreter = 'latex';
            app.p_1Label_18.Text = '$p_{5}$';
            app.p5EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p5EditField2.ValueChangedFcn = createCallbackFcn(app, @p5EditField2ValueChanged, true);
            app.p5EditField2.Value = 5.7;
            app.p_1Label_19 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_19.Interpreter = 'latex';
            app.p_1Label_19.Text = '$p_{6}$';
            app.p6EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p6EditField2.ValueChangedFcn = createCallbackFcn(app, @p6EditField2ValueChanged, true);
            app.p6EditField2.Value = 0.0629;
            app.p_1Label_20 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_20.Interpreter = 'latex';
            app.p_1Label_20.Text = '$p_{7}$';
            app.p7EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p7EditField2.ValueChangedFcn = createCallbackFcn(app, @p7EditField2ValueChanged, true);
            app.p7EditField2.Value = 6.1;
            app.p_1Label_21 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_21.Interpreter = 'latex';
            app.p_1Label_21.Text = '$p_{8}$';
            app.p8EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p8EditField2.ValueChangedFcn = createCallbackFcn(app, @p8EditField2ValueChanged, true);
            app.p8EditField2.Value = 18;
            app.p_1Label_22 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_22.Interpreter = 'latex';
            app.p_1Label_22.Text = '$p_{9}$';
            app.p9EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p9EditField2.ValueChangedFcn = createCallbackFcn(app, @p9EditField2ValueChanged, true);
            app.p9EditField2.Value = 2.058;
            app.p_1Label_23 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_23.Interpreter = 'latex';
            app.p_1Label_23.Text = '$p_{10}$';
            app.p10EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p10EditField2.ValueChangedFcn = createCallbackFcn(app, @p10EditField2ValueChanged, true);
            app.p10EditField2.Value = 803;
            app.p_1Label_24 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_24.Interpreter = 'latex';
            app.p_1Label_24.Text = '$G_{\mathrm{Kslow1}}$';
            app.GKslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.GKslow1EditField.ValueChangedFcn = createCallbackFcn(app, @GKslow1EditFieldValueChanged, true);
            app.GKslow1EditField.Value = 0.16;
            app.p_1Label_25 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_25.Interpreter = 'latex';
            app.p_1Label_25.Text = '$p_{1}$';
            app.p1EditField3 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1EditField3.ValueChangedFcn = createCallbackFcn(app, @p1EditField3ValueChanged, true);
            app.p1EditField3.Value = 4912;
            app.p_1Label_26 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_26.Interpreter = 'latex';
            app.p_1Label_26.Text = '$p_{2}$';
            app.p2EditField3 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2EditField3.ValueChangedFcn = createCallbackFcn(app, @p2EditField3ValueChanged, true);
            app.p2EditField3.Value = 5334;
            app.p_1Label_27 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_27.Interpreter = 'latex';
            app.p_1Label_27.Text = '$G_{\mathrm{Kslow1}}$';
            app.GKslow2EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.GKslow2EditField.ValueChangedFcn = createCallbackFcn(app, @GKslow2EditFieldValueChanged, true);
            app.GKslow2EditField.Value = 0.16;
            app.p_1Label_28 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_28.Interpreter = 'latex';
            app.p_1Label_28.Text = '$p_{1}$';
            app.p1EditField4 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1EditField4.ValueChangedFcn = createCallbackFcn(app, @p1EditField4ValueChanged, true);
            app.p1EditField4.Value = 0.0862;
            app.p_1Label_29 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_29.Interpreter = 'latex';
            app.p_1Label_29.Text = '$p_{2}$';
            app.p2EditField4 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2EditField4.ValueDisplayFormat = '%11.5g';
            app.p2EditField4.ValueChangedFcn = createCallbackFcn(app, @p2EditField4ValueChanged, true);
            app.p2EditField4.Value = 1235.5;
            app.p_1Label_30 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_30.Interpreter = 'latex';
            app.p_1Label_30.Text = '$p_{3}$';
            app.p3EditField4 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3EditField4.ValueChangedFcn = createCallbackFcn(app, @p3EditField4ValueChanged, true);
            app.p3EditField4.Value = 13.17;
            app.p_1Label_31 = uilabel(app.Kv15Kv21K2PTab);
            app.p_1Label_31.Interpreter = 'latex';
            app.p_1Label_31.Text = '$G_{\mathrm{Kslow1}}$';
            app.GKssEditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.GKssEditField.ValueChangedFcn = createCallbackFcn(app, @GKssEditFieldValueChanged, true);
            app.GKssEditField.Value = 0.0611;
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroupCalibration = uitabgroup(app.RightPanel);
            app.ModelOutputTab = uitab(app.TabGroupCalibration);
            app.ModelOutputTab.Title = 'Model Output';
            app.UIAxesModelIndv = uiaxes(app.ModelOutputTab);
            title(app.UIAxesModelIndv, 'Individual K^{+} Currents')
            xlabel(app.UIAxesModelIndv, 'Time (ms)')
            ylabel(app.UIAxesModelIndv, 'K^{+} Current (pA/pF)')
            zlabel(app.UIAxesModelIndv, 'Z')
            app.UIAxesModelSum = uiaxes(app.ModelOutputTab);
            title(app.UIAxesModelSum, 'I_{Ksum}')
            xlabel(app.UIAxesModelSum, 'Time (ms)')
            ylabel(app.UIAxesModelSum, 'k^{+} Current (pA/pF)')
            zlabel(app.UIAxesModelSum, 'Z')
            app.RunCalibrationButton = uibutton(app.ModelOutputTab, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Text = 'Run Calibration';
            app.ModelParametersSaveButton = uibutton(app.ModelOutputTab, 'push');
            app.ModelParametersSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelParametersSaveButtonPushed, true);
            app.ModelParametersSaveButton.Text = 'Model Parameters Save';
            app.ModelParametersImportButton = uibutton(app.ModelOutputTab, 'push');
            app.ModelParametersImportButton.ButtonPushedFcn = createCallbackFcn(app, @ModelParametersImportButtonPushed, true);
            app.ModelParametersImportButton.Text = 'Model Parameters Import';
            app.VoltageDropDownLabel = uilabel(app.ModelOutputTab);
            app.VoltageDropDownLabel.Text = 'Voltage';
            app.VoltageDropDown = uidropdown(app.ModelOutputTab);
            app.VoltageDropDown.Items = {'-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50'};
            app.VoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageDropDownValueChanged, true);
            app.VoltageDropDown.Value = '-50';
            app.FileEditFieldLabel = uilabel(app.ModelOutputTab);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.ModelOutputTab, 'text');
            app.RMSEEditFieldLabel = uilabel(app.ModelOutputTab);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.ModelOutputTab, 'numeric');
            app.Kv42TabCalib = uitab(app.TabGroupCalibration);
            app.Kv42TabCalib.Title = 'Kv4.2';
            app.KvKtof = uipanel(app.Kv42TabCalib);
            app.KvKtof.Title = 'Kinetics Modeling Results of Kv4.2 (IKto,f)';
            app.Kv15Kv21K2PTabCalib = uitab(app.TabGroupCalibration);
            app.Kv15Kv21K2PTabCalib.Title = 'Kv1.5 / Kv2.1 / K2P';
            app.KvKslow1 = uipanel(app.Kv15Kv21K2PTabCalib);
            app.KvKslow1.Title = 'Kinetics Modeling Results of Kv1.5 (IKslow1) or Kv1.5/2.1 (IKslow)';
            app.KvKslow2 = uipanel(app.Kv15Kv21K2PTabCalib);
            app.KvKslow2.Title = 'Kinetics Modeling Results of Kv 2.1 (IKslow2)';
            app.KvKss = uipanel(app.Kv15Kv21K2PTabCalib);
            app.KvKss.Title = 'Kinetics Modeling Results of K2P Family (IKss)';
            app.ExperimentalProtocolDataLabel = uilabel(app.UIFigure);
            app.ExperimentalProtocolDataLabel.Text = 'Experimental Protocol & Data';
            app.ComputerModelLabel = uilabel(app.UIFigure);
            app.ComputerModelLabel.Text = 'Computer Model';
            app.ModelOutputsKineticsModelingResultsLabel = uilabel(app.UIFigure);
            app.ModelOutputsKineticsModelingResultsLabel.Text = 'Model Outputs & Kinetics Modeling Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end