classdef IKtos_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image           matlab.ui.control.Image
        p1EditField     matlab.ui.control.NumericEditField
        p2EditField     matlab.ui.control.NumericEditField
        p3EditField     matlab.ui.control.NumericEditField
        p4EditField     matlab.ui.control.NumericEditField
        p5EditField     matlab.ui.control.NumericEditField
        p6EditField     matlab.ui.control.NumericEditField
        p7EditField     matlab.ui.control.NumericEditField
        p8EditField     matlab.ui.control.NumericEditField
        p9EditField     matlab.ui.control.NumericEditField
        p10EditField    matlab.ui.control.NumericEditField
        p11EditField    matlab.ui.control.NumericEditField
        GKtosEditField  matlab.ui.control.NumericEditField
        EnterButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        KvApp % main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.KvApp = mainapp;
            
            app.p1EditField.Value = app.KvApp.p1Ktos;
            app.p2EditField.Value = app.KvApp.p2Ktos;
            app.p3EditField.Value = app.KvApp.p3Ktos;
            app.p4EditField.Value = app.KvApp.p4Ktos;
            app.p5EditField.Value = app.KvApp.p5Ktos;
            app.p6EditField.Value = app.KvApp.p6Ktos;
            app.p7EditField.Value = app.KvApp.p7Ktos;
            app.p8EditField.Value = app.KvApp.p8Ktos;
            app.p9EditField.Value = app.KvApp.p9Ktos;
            app.p10EditField.Value = app.KvApp.p10Ktos;
            app.p11EditField.Value = app.KvApp.p11Ktos;
            app.GKtosEditField.Value = app.KvApp.GKtos;            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            param = zeros(12, 1);
            param(1) = app.p1EditField.Value;
            param(2) = app.p2EditField.Value;
            param(3) = app.p3EditField.Value;
            param(4) = app.p4EditField.Value;
            param(5) = app.p5EditField.Value;
            param(6) = app.p6EditField.Value;
            param(7) = app.p7EditField.Value;
            param(8) = app.p8EditField.Value;
            param(9) = app.p9EditField.Value;
            param(10) = app.p10EditField.Value;
            param(11) = app.p11EditField.Value;
            param(12) = app.GKtosEditField.Value;
            
            update_ktos(app.KvApp, param);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'iktos.PNG';
            app.p1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p1EditField.Value = 22.5;
            app.p2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2EditField.Value = 7.7;
            app.p3EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3EditField.Value = 0.493;
            app.p4EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4EditField.Value = 0.0629;
            app.p5EditField = uieditfield(app.UIFigure, 'numeric');
            app.p5EditField.Value = 2.058;
            app.p6EditField = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField.Value = 45.2;
            app.p7EditField = uieditfield(app.UIFigure, 'numeric');
            app.p7EditField.Value = 5.7;
            app.p8EditField = uieditfield(app.UIFigure, 'numeric');
            app.p8EditField.Value = 270;
            app.p9EditField = uieditfield(app.UIFigure, 'numeric');
            app.p9EditField.Value = 1050;
            app.p10EditField = uieditfield(app.UIFigure, 'numeric');
            app.p10EditField.Value = 45.2;
            app.p11EditField = uieditfield(app.UIFigure, 'numeric');
            app.p11EditField.Value = 5.7;
            app.GKtosEditField = uieditfield(app.UIFigure, 'numeric');
            app.GKtosEditField.Value = 0.0629;
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IKtos_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end