classdef calib_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectvoltageDropDownLabel   matlab.ui.control.Label
        SelectvoltageDropDown        matlab.ui.control.DropDown
        MaxstepEditFieldLabel        matlab.ui.control.Label
        MaxstepEditField             matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        NumberofiterationsEditField  matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        UIAxesAll                    matlab.ui.control.UIAxes
        UIAxesIndv                   matlab.ui.control.UIAxes
    end

    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Store original button text
            orig_button_text = app.RunButton.Text;

            % When the function ends, return the original button state
            cleanup = onCleanup(@()set(app.RunButton, 'Text',orig_button_text, 'Icon',''));

            % Change button name to "Processing"
            app.RunButton.Text = 'Processing...';
            
            % Put text on top of icon
            app.RunButton.IconAlignment = 'bottom';

            % Create waitbar with same color as button
            wbar = permute(repmat(app.RunButton.BackgroundColor,15,1,1000),[1,3,2]);

            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;

            % Load the empty waitbar to the button
            app.RunButton.Icon = wbar;
            
            n = 10; 
            x = 0;
            for i = 1:n
                x = x+1;
                % % % % % % % % % % % % % % % % % % 
                % %  YOUR LOOP STUFF GOES HERE  % %
                % % % % % % % % % % % % % % % % % % 
                
                % Update progress bar 
                currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2); 
                app.RunButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                app.RunButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.RunButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                
                % Pause to slow down animation
                % pause(.5)
            end
            app.MaxstepEditField.Value = x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectvoltageDropDownLabel = uilabel(app.UIFigure);
            app.SelectvoltageDropDownLabel.Text = 'Select voltage';
            app.SelectvoltageDropDown = uidropdown(app.UIFigure);
            app.MaxstepEditFieldLabel = uilabel(app.UIFigure);
            app.MaxstepEditFieldLabel.Text = 'Max step';
            app.MaxstepEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxstepEditField.Value = 100000;
            app.NumberofiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofiterationsEditField.Value = 30;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxesAll = uiaxes(app.UIFigure);
            title(app.UIAxesAll, {'Model Calibration'; ''})
            xlabel(app.UIAxesAll, 'Time (ms)')
            ylabel(app.UIAxesAll, 'Current (pA/pF)')
            zlabel(app.UIAxesAll, 'Z')
            app.UIAxesIndv = uiaxes(app.UIFigure);
            title(app.UIAxesIndv, 'Model Calibration - Comparison')
            xlabel(app.UIAxesIndv, 'Time (ms)')
            ylabel(app.UIAxesIndv, 'Current (pA/pF)')
            zlabel(app.UIAxesIndv, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calib_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end