classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        ProtocolTab                   matlab.ui.container.Tab
        HoldingtimemsEditFieldLabel   matlab.ui.control.Label
        HoldingtimemsEditField        matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField   matlab.ui.control.NumericEditField
        ClampvoltagemVEditFieldLabel  matlab.ui.control.Label
        ClampvoltagemVEditField       matlab.ui.control.NumericEditField
        VoltagetimemsEditFieldLabel   matlab.ui.control.Label
        VoltagetimemsEditField        matlab.ui.control.NumericEditField
        EnterButton                   matlab.ui.control.Button
        SteadystatecurrentpApFEditFieldLabel  matlab.ui.control.Label
        SteadystatecurrentpApFEditField  matlab.ui.control.NumericEditField
        EkEditFieldLabel              matlab.ui.control.Label
        EkEditField                   matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        IKtofTab                      matlab.ui.container.Tab
        p1KnobLabel                   matlab.ui.control.Label
        p1Knob                        matlab.ui.control.Knob
        p2KnobLabel                   matlab.ui.control.Label
        p2Knob                        matlab.ui.control.Knob
        p3KnobLabel                   matlab.ui.control.Label
        p3Knob                        matlab.ui.control.Knob
        p4KnobLabel                   matlab.ui.control.Label
        p4Knob                        matlab.ui.control.Knob
        p5KnobLabel                   matlab.ui.control.Label
        p5Knob                        matlab.ui.control.Knob
        p6KnobLabel                   matlab.ui.control.Label
        p6Knob                        matlab.ui.control.Knob
        p7KnobLabel                   matlab.ui.control.Label
        p7Knob                        matlab.ui.control.Knob
        p8KnobLabel                   matlab.ui.control.Label
        p8Knob                        matlab.ui.control.Knob
        p9KnobLabel                   matlab.ui.control.Label
        p9Knob                        matlab.ui.control.Knob
        p10KnobLabel                  matlab.ui.control.Label
        p10Knob                       matlab.ui.control.Knob
        p11KnobLabel                  matlab.ui.control.Label
        p11Knob                       matlab.ui.control.Knob
        p12KnobLabel                  matlab.ui.control.Label
        p12Knob                       matlab.ui.control.Knob
        GKtofKnobLabel                matlab.ui.control.Label
        GKtofKnob                     matlab.ui.control.Knob
        TextArea                      matlab.ui.control.TextArea
        PlotButton                    matlab.ui.control.Button
        p1EditFieldLabel              matlab.ui.control.Label
        p1EditField                   matlab.ui.control.NumericEditField
        p2EditFieldLabel              matlab.ui.control.Label
        p2EditField                   matlab.ui.control.NumericEditField
        p3EditFieldLabel              matlab.ui.control.Label
        p3EditField                   matlab.ui.control.NumericEditField
        p4EditFieldLabel              matlab.ui.control.Label
        p4EditField                   matlab.ui.control.NumericEditField
        p5EditFieldLabel              matlab.ui.control.Label
        p5EditField                   matlab.ui.control.NumericEditField
        p6EditFieldLabel              matlab.ui.control.Label
        p6EditField                   matlab.ui.control.NumericEditField
        p7EditFieldLabel              matlab.ui.control.Label
        p7EditField                   matlab.ui.control.NumericEditField
        p8EditFieldLabel              matlab.ui.control.Label
        p8EditField                   matlab.ui.control.NumericEditField
        p9EditFieldLabel              matlab.ui.control.Label
        p9EditField                   matlab.ui.control.NumericEditField
        p10EditFieldLabel             matlab.ui.control.Label
        p10EditField                  matlab.ui.control.NumericEditField
        p11EditFieldLabel             matlab.ui.control.Label
        p11EditField                  matlab.ui.control.NumericEditField
        p12EditFieldLabel             matlab.ui.control.Label
        p12EditField                  matlab.ui.control.NumericEditField
        GKtofEditFieldLabel           matlab.ui.control.Label
        GKtofEditField                matlab.ui.control.NumericEditField
        IKurTab                       matlab.ui.container.Tab
        TextArea_2                    matlab.ui.control.TextArea
        PlotButton_2                  matlab.ui.control.Button
        p1Knob_2Label                 matlab.ui.control.Label
        p1Knob_2                      matlab.ui.control.Knob
        p2Knob_2Label                 matlab.ui.control.Label
        p2Knob_2                      matlab.ui.control.Knob
        p3Knob_2Label                 matlab.ui.control.Label
        p3Knob_2                      matlab.ui.control.Knob
        p4Knob_2Label                 matlab.ui.control.Label
        p4Knob_2                      matlab.ui.control.Knob
        p5Knob_2Label                 matlab.ui.control.Label
        p5Knob_2                      matlab.ui.control.Knob
        p6Knob_2Label                 matlab.ui.control.Label
        p6Knob_2                      matlab.ui.control.Knob
        p7Knob_2Label                 matlab.ui.control.Label
        p7Knob_2                      matlab.ui.control.Knob
        p8Knob_2Label                 matlab.ui.control.Label
        p8Knob_2                      matlab.ui.control.Knob
        p9Knob_2Label                 matlab.ui.control.Label
        p9Knob_2                      matlab.ui.control.Knob
        p10Knob_2Label                matlab.ui.control.Label
        p10Knob_2                     matlab.ui.control.Knob
        p11Knob_2Label                matlab.ui.control.Label
        p11Knob_2                     matlab.ui.control.Knob
        GKurKnobLabel                 matlab.ui.control.Label
        GKurKnob                      matlab.ui.control.Knob
        p1EditField_2Label            matlab.ui.control.Label
        p1EditField_2                 matlab.ui.control.NumericEditField
        p2EditField_2Label            matlab.ui.control.Label
        p2EditField_2                 matlab.ui.control.NumericEditField
        p3EditField_2Label            matlab.ui.control.Label
        p3EditField_2                 matlab.ui.control.NumericEditField
        p4EditField_2Label            matlab.ui.control.Label
        p4EditField_2                 matlab.ui.control.NumericEditField
        p5EditField_2Label            matlab.ui.control.Label
        p5EditField_2                 matlab.ui.control.NumericEditField
        p6EditField_2Label            matlab.ui.control.Label
        p6EditField_2                 matlab.ui.control.NumericEditField
        p7EditField_2Label            matlab.ui.control.Label
        p7EditField_2                 matlab.ui.control.NumericEditField
        p8EditField_2Label            matlab.ui.control.Label
        p8EditField_2                 matlab.ui.control.NumericEditField
        p9EditField_2Label            matlab.ui.control.Label
        p9EditField_2                 matlab.ui.control.NumericEditField
        p10EditField_2Label           matlab.ui.control.Label
        p10EditField_2                matlab.ui.control.NumericEditField
        p11EditField_2Label           matlab.ui.control.Label
        p11EditField_2                matlab.ui.control.NumericEditField
        GKurEditFieldLabel            matlab.ui.control.Label
        GKurEditField                 matlab.ui.control.NumericEditField
        IKssTab                       matlab.ui.container.Tab
        RightPanel                    matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        tH % holding time
        tP % voltage-clamp time
        t % entire time
        yss % steady-state current
        yKtof
        yKur
    end
    
    methods (Access = private)
        function [tH, tP, t] = time_space(app)
            tH = 0:app.HoldingtimemsEditField.Value;
            tP = 0:app.VoltagetimemsEditField.Value;
            tP_shift = tP + app.HoldingtimemsEditField.Value + 1;
            t = [tH, tP_shift];
        end

        function [current_trc] = gen_Iss(app)
            ss_val = app.SteadystatecurrentpApFEditField.Value;
            current_trcH = zeros(1, length(app.tH));
            current_trcP = ones(1, length(app.tP))*ss_val;
            current_trc = [current_trcH, current_trcP];
            current_trc = transpose(current_trc);
        end
        
        function [current_trc] = gen_IKtof(app)
            param = zeros(13, 1);
            param(1) = app.p1Knob.Value;
            param(2) = app.p2Knob.Value;
            param(3) = app.p3Knob.Value;
            param(4) = app.p4Knob.Value;
            param(5) = app.p5Knob.Value;
            param(6) = app.p6Knob.Value;
            param(7) = app.p7Knob.Value;
            param(8) = app.p8Knob.Value;
            param(9) = app.p9Knob.Value;
            param(10) = app.p10Knob.Value;
            param(11) = app.p11Knob.Value;
            param(12) = app.p12Knob.Value;
            param(13) = app.GKtofKnob.Value;
            param(14) = app.EkEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKtof(param, app.HoldingpotentialmVEditField.Value, app.ClampvoltagemVEditField.Value, tt);
        end
        
        function [current_trc] = gen_IKur(app)
            param = zeros(12, 1);
            param(1) = app.p1Knob_2.Value;
            param(2) = app.p2Knob_2.Value;
            param(3) = app.p3Knob_2.Value;
            param(4) = app.p4Knob_2.Value;
            param(5) = app.p5Knob_2.Value;
            param(6) = app.p6Knob_2.Value;
            param(7) = app.p7Knob_2.Value;
            param(8) = app.p8Knob_2.Value;
            param(9) = app.p9Knob_2.Value;
            param(10) = app.p10Knob_2.Value;
            param(11) = app.p11Knob_2.Value;
            param(12) = app.GKurKnob.Value;
            param(13) = app.EkEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKur(param, app.HoldingpotentialmVEditField.Value, app.ClampvoltagemVEditField.Value, tt);
        end
        
        function plot_protocol(app)            
            yH = ones(1, length(app.tH))*app.HoldingpotentialmVEditField.Value;
            yP = ones(1, length(app.tP))*app.ClampvoltagemVEditField.Value;
            y = [yH, yP];
            
            plot(app.UIAxes, app.t, y, 'LineWidth',1.5)
        end
        
        function plot_indvid(app, y)
            plot(app.UIAxes2, app.t, y, "LineWidth",1.5)
        end
        
        function plot_all(app)
            yKsum = app.yss + app.yKtof + app.yKur;
                
            % Iss
            plot(app.UIAxes3, app.t, app.yss, "LineStyle","--", "LineWidth",1.5, "Color","black")
            
            hold(app.UIAxes3, "on")
            % IKtof
            plot(app.UIAxes3, app.t, app.yKtof, "LineStyle",":", "LineWidth",1.5, "Color","black")
            % IKur
            plot(app.UIAxes3, app.t, app.yKur, "LineStyle","-.", "LineWidth",1.5, "Color","black")
            % IKsum
            plot(app.UIAxes3, app.t, yKsum, "LineStyle","-", "LineWidth",1.5, "Color","red")
            hold(app.UIAxes3, "off")
            legend(app.UIAxes3, "I_{ss}", "I_{Kto,f}", "I_{Kur}", "I_{Ksum}", "Location","best")
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % generate time space
            [app.tH, app.tP, app.t] = time_space(app);
            
            % plot default voltage clamp protocol
            plot_protocol(app);
            
            % plot steady-state current
            app.yss = gen_Iss(app);
            plot(app.UIAxes3, app.t, app.yss, "LineStyle","--", "LineWidth",1.5, "Color","black")
            legend(app.UIAxes3, "I_{ss}", "Location","best")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {630, 630};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {595, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: EnterButton, GKtofKnob
        function EnterButtonPushed(app, event)
            [app.tH, app.tP, app.t] = time_space(app);
            plot_protocol(app);
            
            app.yss = gen_Iss(app);
            plot(app.UIAxes3, app.t, app.yss, "LineStyle","--", "LineWidth",1.5, "Color","black")
            legend(app.UIAxes3, "I_{ss}", "Location","best")
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.yKtof = gen_IKtof(app);
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);            
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            app.yKtof = gen_IKtof(app);
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: GKtofEditField, GKtofKnob
        function GKtofKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtofKnob.Value = changingValue;
            app.GKtofEditField.Value = changingValue;          
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p1EditField, p1Knob
        function p1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob.Value = changingValue;
            app.p1EditField.Value = changingValue;            
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p2EditField, p2Knob
        function p2KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob.Value = changingValue;
            app.p2EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p3EditField, p3Knob
        function p3KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob.Value = changingValue;
            app.p3EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);            
        end

        % Callback function: p4EditField, p4Knob
        function p4KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob.Value = changingValue;
            app.p4EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p5EditField, p5Knob
        function p5KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob.Value = changingValue;
            app.p5EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p6EditField, p6Knob
        function p6KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob.Value = changingValue;
            app.p6EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p7EditField, p7Knob
        function p7KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob.Value = changingValue;
            app.p7EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p8EditField, p8Knob
        function p8KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob.Value = changingValue;
            app.p8EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);
        end

        % Callback function: p9EditField, p9Knob
        function p9KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob.Value = changingValue;
            app.p9EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);            
        end

        % Callback function: p10EditField, p10Knob
        function p10KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob.Value = changingValue;
            app.p10EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);         
        end

        % Callback function: p11EditField, p11Knob
        function p11KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob.Value = changingValue;
            app.p11EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);            
        end

        % Callback function: p12EditField, p12Knob
        function p12KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p12Knob.Value = changingValue;
            app.p12EditField.Value = changingValue;
            
            app.yKtof = gen_IKtof(app);
            plot_indvid(app, app.yKtof);
            plot_all(app);           
        end

        % Callback function: GKurEditField, GKurKnob
        function GKurKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKurKnob.Value = changingValue;
            app.GKurEditField.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p1EditField_2, p1Knob_2
        function p1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_2.Value = changingValue;
            app.p1EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p2EditField_2, p2Knob_2
        function p2Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_2.Value = changingValue;
            app.p2EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p3EditField_2, p3Knob_2
        function p3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_2.Value = changingValue;
            app.p3EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p4EditField_2, p4Knob_2
        function p4Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_2.Value = changingValue;
            app.p4EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p5EditField_2, p5Knob_2
        function p5Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_2.Value = changingValue;
            app.p5EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p6EditField_2, p6Knob_2
        function p6Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_2.Value = changingValue;
            app.p6EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p7EditField_2, p7Knob_2
        function p7Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_2.Value = changingValue;
            app.p7EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);            
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p8EditField_2, p8Knob_2
        function p8Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_2.Value = changingValue;
            app.p8EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p9EditField_2, p9Knob_2
        function p9Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_2.Value = changingValue;
            app.p9EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p10EditField_2, p10Knob_2
        function p10Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob_2.Value = changingValue;
            app.p10EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);
        end

        % Callback function: p11EditField_2, p11Knob_2
        function p11Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob_2.Value = changingValue;
            app.p11EditField_2.Value = changingValue;
            
            app.yKur = gen_IKur(app);
            plot_indvid(app, app.yKur);
            plot_all(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ProtocolTab = uitab(app.TabGroup);
            app.ProtocolTab.Title = 'Protocol';
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingtimemsEditField.Value = 100;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampvoltagemVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampvoltagemVEditFieldLabel.Text = 'Clamp voltage (mV)';
            app.ClampvoltagemVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampvoltagemVEditField.Value = 50;
            app.VoltagetimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.VoltagetimemsEditFieldLabel.Text = 'Voltage time (ms)';
            app.VoltagetimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.VoltagetimemsEditField.Value = 4500;
            app.EnterButton = uibutton(app.ProtocolTab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.SteadystatecurrentpApFEditFieldLabel = uilabel(app.ProtocolTab);
            app.SteadystatecurrentpApFEditFieldLabel.Text = 'Steady-state current (pA/pF)';
            app.SteadystatecurrentpApFEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.SteadystatecurrentpApFEditField.Value = 4.2;
            app.EkEditFieldLabel = uilabel(app.ProtocolTab);
            app.EkEditFieldLabel.Text = 'Ek';
            app.EkEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.EkEditField.Value = -91.1;
            app.UIAxes = uiaxes(app.ProtocolTab);
            title(app.UIAxes, 'Voltage Clamp Protocol')
            xlabel(app.UIAxes, 'ms')
            ylabel(app.UIAxes, 'mV')
            zlabel(app.UIAxes, 'Z')
            app.IKtofTab = uitab(app.TabGroup);
            app.IKtofTab.Title = 'IKtof';
            app.p1KnobLabel = uilabel(app.IKtofTab);
            app.p1KnobLabel.Text = 'p1';
            app.p1Knob = uiknob(app.IKtofTab, 'continuous');
            app.p1Knob.Limits = [-60 60];
            app.p1Knob.ValueChangingFcn = createCallbackFcn(app, @p1KnobValueChanging, true);
            app.p1Knob.Value = 30;
            app.p2KnobLabel = uilabel(app.IKtofTab);
            app.p2KnobLabel.Text = 'p2';
            app.p2Knob = uiknob(app.IKtofTab, 'continuous');
            app.p2Knob.Limits = [-60 60];
            app.p2Knob.ValueChangingFcn = createCallbackFcn(app, @p2KnobValueChanging, true);
            app.p2Knob.Value = 13.5;
            app.p3KnobLabel = uilabel(app.IKtofTab);
            app.p3KnobLabel.Text = 'p3';
            app.p3Knob = uiknob(app.IKtofTab, 'continuous');
            app.p3Knob.Limits = [-60 80];
            app.p3Knob.ValueChangingFcn = createCallbackFcn(app, @p3KnobValueChanging, true);
            app.p3Knob.Value = 33.5;
            app.p4KnobLabel = uilabel(app.IKtofTab);
            app.p4KnobLabel.Text = 'p4';
            app.p4Knob = uiknob(app.IKtofTab, 'continuous');
            app.p4Knob.Limits = [0 15];
            app.p4Knob.ValueChangingFcn = createCallbackFcn(app, @p4KnobValueChanging, true);
            app.p4Knob.Value = 7;
            app.p5KnobLabel = uilabel(app.IKtofTab);
            app.p5KnobLabel.Text = 'p5';
            app.p5Knob = uiknob(app.IKtofTab, 'continuous');
            app.p5Knob.Limits = [0 1.5];
            app.p5Knob.ValueChangingFcn = createCallbackFcn(app, @p5KnobValueChanging, true);
            app.p5Knob.Value = 0.18064;
            app.p6KnobLabel = uilabel(app.IKtofTab);
            app.p6KnobLabel.Text = 'p6';
            app.p6Knob = uiknob(app.IKtofTab, 'continuous');
            app.p6Knob.Limits = [0 0.5];
            app.p6Knob.ValueChangingFcn = createCallbackFcn(app, @p6KnobValueChanging, true);
            app.p6Knob.Value = 0.03577;
            app.p7KnobLabel = uilabel(app.IKtofTab);
            app.p7KnobLabel.Text = 'p7';
            app.p7Knob = uiknob(app.IKtofTab, 'continuous');
            app.p7Knob.Limits = [0 1.5];
            app.p7Knob.ValueChangingFcn = createCallbackFcn(app, @p7KnobValueChanging, true);
            app.p7Knob.Value = 0.3956;
            app.p8KnobLabel = uilabel(app.IKtofTab);
            app.p8KnobLabel.Text = 'p8';
            app.p8Knob = uiknob(app.IKtofTab, 'continuous');
            app.p8Knob.Limits = [-0.5 0.5];
            app.p8Knob.ValueChangingFcn = createCallbackFcn(app, @p8KnobValueChanging, true);
            app.p8Knob.Value = 0.06237;
            app.p9KnobLabel = uilabel(app.IKtofTab);
            app.p9KnobLabel.Text = 'p9';
            app.p9Knob = uiknob(app.IKtofTab, 'continuous');
            app.p9Knob.Limits = [0 0.001];
            app.p9Knob.ValueChangingFcn = createCallbackFcn(app, @p9KnobValueChanging, true);
            app.p9Knob.Value = 0.000152;
            app.p10KnobLabel = uilabel(app.IKtofTab);
            app.p10KnobLabel.Text = 'p10';
            app.p10Knob = uiknob(app.IKtofTab, 'continuous');
            app.p10Knob.Limits = [0 0.15];
            app.p10Knob.ValueChangingFcn = createCallbackFcn(app, @p10KnobValueChanging, true);
            app.p10Knob.Value = 0.067083;
            app.p11KnobLabel = uilabel(app.IKtofTab);
            app.p11KnobLabel.Text = 'p11';
            app.p11Knob = uiknob(app.IKtofTab, 'continuous');
            app.p11Knob.Limits = [0 0.002];
            app.p11Knob.ValueChangingFcn = createCallbackFcn(app, @p11KnobValueChanging, true);
            app.p11Knob.Value = 0.00095;
            app.p12KnobLabel = uilabel(app.IKtofTab);
            app.p12KnobLabel.Text = 'p12';
            app.p12Knob = uiknob(app.IKtofTab, 'continuous');
            app.p12Knob.Limits = [0 0.1];
            app.p12Knob.ValueChangingFcn = createCallbackFcn(app, @p12KnobValueChanging, true);
            app.p12Knob.Value = 0.051335;
            app.GKtofKnobLabel = uilabel(app.IKtofTab);
            app.GKtofKnobLabel.Text = 'GKtof';
            app.GKtofKnob = uiknob(app.IKtofTab, 'continuous');
            app.GKtofKnob.Limits = [0 2];
            app.GKtofKnob.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.GKtofKnob.ValueChangingFcn = createCallbackFcn(app, @GKtofKnobValueChanging, true);
            app.GKtofKnob.Value = 0.4067;
            app.TextArea = uitextarea(app.IKtofTab);
            app.TextArea.Value = {'IKtof'; 'Transient outward'; 'K+ current '};
            app.PlotButton = uibutton(app.IKtofTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.p1EditFieldLabel = uilabel(app.IKtofTab);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p1EditField.ValueChangedFcn = createCallbackFcn(app, @p1KnobValueChanging, true);
            app.p1EditField.Value = 30;
            app.p2EditFieldLabel = uilabel(app.IKtofTab);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p2EditField.ValueChangedFcn = createCallbackFcn(app, @p2KnobValueChanging, true);
            app.p2EditField.Value = 13.5;
            app.p3EditFieldLabel = uilabel(app.IKtofTab);
            app.p3EditFieldLabel.Text = 'p3';
            app.p3EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p3EditField.ValueChangedFcn = createCallbackFcn(app, @p3KnobValueChanging, true);
            app.p3EditField.Value = 33.5;
            app.p4EditFieldLabel = uilabel(app.IKtofTab);
            app.p4EditFieldLabel.Text = 'p4';
            app.p4EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p4EditField.ValueChangedFcn = createCallbackFcn(app, @p4KnobValueChanging, true);
            app.p4EditField.Value = 7;
            app.p5EditFieldLabel = uilabel(app.IKtofTab);
            app.p5EditFieldLabel.Text = 'p5';
            app.p5EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p5EditField.ValueChangedFcn = createCallbackFcn(app, @p5KnobValueChanging, true);
            app.p5EditField.Value = 0.18064;
            app.p6EditFieldLabel = uilabel(app.IKtofTab);
            app.p6EditFieldLabel.Text = 'p6';
            app.p6EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p6EditField.ValueChangedFcn = createCallbackFcn(app, @p6KnobValueChanging, true);
            app.p6EditField.Value = 0.03577;
            app.p7EditFieldLabel = uilabel(app.IKtofTab);
            app.p7EditFieldLabel.Text = 'p7';
            app.p7EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p7EditField.ValueChangedFcn = createCallbackFcn(app, @p7KnobValueChanging, true);
            app.p7EditField.Value = 0.3956;
            app.p8EditFieldLabel = uilabel(app.IKtofTab);
            app.p8EditFieldLabel.Text = 'p8';
            app.p8EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p8EditField.ValueChangedFcn = createCallbackFcn(app, @p8KnobValueChanging, true);
            app.p8EditField.Value = 0.06237;
            app.p9EditFieldLabel = uilabel(app.IKtofTab);
            app.p9EditFieldLabel.Text = 'p9';
            app.p9EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p9EditField.ValueChangedFcn = createCallbackFcn(app, @p9KnobValueChanging, true);
            app.p9EditField.Value = 0.000152;
            app.p10EditFieldLabel = uilabel(app.IKtofTab);
            app.p10EditFieldLabel.Text = 'p10';
            app.p10EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p10EditField.ValueChangedFcn = createCallbackFcn(app, @p10KnobValueChanging, true);
            app.p10EditField.Value = 0.067083;
            app.p11EditFieldLabel = uilabel(app.IKtofTab);
            app.p11EditFieldLabel.Text = 'p11';
            app.p11EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p11EditField.ValueChangedFcn = createCallbackFcn(app, @p11KnobValueChanging, true);
            app.p11EditField.Value = 0.00095;
            app.p12EditFieldLabel = uilabel(app.IKtofTab);
            app.p12EditFieldLabel.Text = 'p12';
            app.p12EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p12EditField.ValueChangedFcn = createCallbackFcn(app, @p12KnobValueChanging, true);
            app.p12EditField.Value = 0.051335;
            app.GKtofEditFieldLabel = uilabel(app.IKtofTab);
            app.GKtofEditFieldLabel.Text = 'GKtof';
            app.GKtofEditField = uieditfield(app.IKtofTab, 'numeric');
            app.GKtofEditField.ValueChangedFcn = createCallbackFcn(app, @GKtofKnobValueChanging, true);
            app.GKtofEditField.Value = 0.4067;
            app.IKurTab = uitab(app.TabGroup);
            app.IKurTab.Title = 'IKur';
            app.TextArea_2 = uitextarea(app.IKurTab);
            app.TextArea_2.Value = {'IKur'; 'Ultrarapidly activating delayed rectifier K+ current'};
            app.PlotButton_2 = uibutton(app.IKurTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.p1Knob_2Label = uilabel(app.IKurTab);
            app.p1Knob_2Label.Text = 'p1';
            app.p1Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p1Knob_2.Limits = [-60 60];
            app.p1Knob_2.ValueChangingFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1Knob_2.Value = 22.5;
            app.p2Knob_2Label = uilabel(app.IKurTab);
            app.p2Knob_2Label.Text = 'p2';
            app.p2Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p2Knob_2.Limits = [0 20];
            app.p2Knob_2.ValueChangingFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2Knob_2.Value = 7.7;
            app.p3Knob_2Label = uilabel(app.IKurTab);
            app.p3Knob_2Label.Text = 'p3';
            app.p3Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p3Knob_2.Limits = [0 2];
            app.p3Knob_2.ValueChangingFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3Knob_2.Value = 0.493;
            app.p4Knob_2Label = uilabel(app.IKurTab);
            app.p4Knob_2Label.Text = 'p4';
            app.p4Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p4Knob_2.Limits = [0 0.5];
            app.p4Knob_2.ValueChangingFcn = createCallbackFcn(app, @p4Knob_2ValueChanging, true);
            app.p4Knob_2.Value = 0.0629;
            app.p5Knob_2Label = uilabel(app.IKurTab);
            app.p5Knob_2Label.Text = 'p5';
            app.p5Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p5Knob_2.Limits = [-10 10];
            app.p5Knob_2.ValueChangingFcn = createCallbackFcn(app, @p5Knob_2ValueChanging, true);
            app.p5Knob_2.Value = 2.058;
            app.p6Knob_2Label = uilabel(app.IKurTab);
            app.p6Knob_2Label.Text = 'p6';
            app.p6Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p6Knob_2.Limits = [-50 100];
            app.p6Knob_2.ValueChangingFcn = createCallbackFcn(app, @p6Knob_2ValueChanging, true);
            app.p6Knob_2.Value = 45.2;
            app.p7Knob_2Label = uilabel(app.IKurTab);
            app.p7Knob_2Label.Text = 'p7';
            app.p7Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p7Knob_2.Limits = [0 20];
            app.p7Knob_2.ValueChangingFcn = createCallbackFcn(app, @p7Knob_2ValueChanging, true);
            app.p7Knob_2.Value = 5.7;
            app.p8Knob_2Label = uilabel(app.IKurTab);
            app.p8Knob_2Label.Text = 'p8';
            app.p8Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p8Knob_2.Limits = [0 5000];
            app.p8Knob_2.ValueChangingFcn = createCallbackFcn(app, @p8Knob_2ValueChanging, true);
            app.p8Knob_2.Value = 1200;
            app.p9Knob_2Label = uilabel(app.IKurTab);
            app.p9Knob_2Label.Text = 'p9';
            app.p9Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p9Knob_2.Limits = [0 500];
            app.p9Knob_2.ValueChangingFcn = createCallbackFcn(app, @p9Knob_2ValueChanging, true);
            app.p9Knob_2.Value = 170;
            app.p10Knob_2Label = uilabel(app.IKurTab);
            app.p10Knob_2Label.Text = 'p10';
            app.p10Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p10Knob_2.Limits = [-50 100];
            app.p10Knob_2.ValueChangingFcn = createCallbackFcn(app, @p10Knob_2ValueChanging, true);
            app.p10Knob_2.Value = 45.2;
            app.p11Knob_2Label = uilabel(app.IKurTab);
            app.p11Knob_2Label.Text = 'p11';
            app.p11Knob_2 = uiknob(app.IKurTab, 'continuous');
            app.p11Knob_2.Limits = [0 20];
            app.p11Knob_2.ValueChangingFcn = createCallbackFcn(app, @p11Knob_2ValueChanging, true);
            app.p11Knob_2.Value = 5.7;
            app.GKurKnobLabel = uilabel(app.IKurTab);
            app.GKurKnobLabel.Text = 'GKur';
            app.GKurKnob = uiknob(app.IKurTab, 'continuous');
            app.GKurKnob.Limits = [0 2];
            app.GKurKnob.ValueChangingFcn = createCallbackFcn(app, @GKurKnobValueChanging, true);
            app.GKurKnob.Value = 0.16;
            app.p1EditField_2Label = uilabel(app.IKurTab);
            app.p1EditField_2Label.Text = 'p1';
            app.p1EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p1EditField_2.ValueChangedFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1EditField_2.Value = 22.5;
            app.p2EditField_2Label = uilabel(app.IKurTab);
            app.p2EditField_2Label.Text = 'p2';
            app.p2EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p2EditField_2.ValueChangedFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2EditField_2.Value = 7.7;
            app.p3EditField_2Label = uilabel(app.IKurTab);
            app.p3EditField_2Label.Text = 'p3';
            app.p3EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p3EditField_2.ValueChangedFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3EditField_2.Value = 0.493;
            app.p4EditField_2Label = uilabel(app.IKurTab);
            app.p4EditField_2Label.Text = 'p4';
            app.p4EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p4EditField_2.ValueChangedFcn = createCallbackFcn(app, @p4Knob_2ValueChanging, true);
            app.p4EditField_2.Value = 0.0629;
            app.p5EditField_2Label = uilabel(app.IKurTab);
            app.p5EditField_2Label.Text = 'p5';
            app.p5EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p5EditField_2.ValueChangedFcn = createCallbackFcn(app, @p5Knob_2ValueChanging, true);
            app.p5EditField_2.Value = 2.058;
            app.p6EditField_2Label = uilabel(app.IKurTab);
            app.p6EditField_2Label.Text = 'p6';
            app.p6EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p6EditField_2.ValueChangedFcn = createCallbackFcn(app, @p6Knob_2ValueChanging, true);
            app.p6EditField_2.Value = 45.2;
            app.p7EditField_2Label = uilabel(app.IKurTab);
            app.p7EditField_2Label.Text = 'p7';
            app.p7EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p7EditField_2.ValueChangedFcn = createCallbackFcn(app, @p7Knob_2ValueChanging, true);
            app.p7EditField_2.Value = 5.7;
            app.p8EditField_2Label = uilabel(app.IKurTab);
            app.p8EditField_2Label.Text = 'p8';
            app.p8EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p8EditField_2.ValueChangedFcn = createCallbackFcn(app, @p8Knob_2ValueChanging, true);
            app.p8EditField_2.Value = 1200;
            app.p9EditField_2Label = uilabel(app.IKurTab);
            app.p9EditField_2Label.Text = 'p9';
            app.p9EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p9EditField_2.ValueChangedFcn = createCallbackFcn(app, @p9Knob_2ValueChanging, true);
            app.p9EditField_2.Value = 170;
            app.p10EditField_2Label = uilabel(app.IKurTab);
            app.p10EditField_2Label.Text = 'p10';
            app.p10EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p10EditField_2.ValueChangedFcn = createCallbackFcn(app, @p10Knob_2ValueChanging, true);
            app.p10EditField_2.Value = 45.2;
            app.p11EditField_2Label = uilabel(app.IKurTab);
            app.p11EditField_2Label.Text = 'p11';
            app.p11EditField_2 = uieditfield(app.IKurTab, 'numeric');
            app.p11EditField_2.ValueChangedFcn = createCallbackFcn(app, @p11Knob_2ValueChanging, true);
            app.p11EditField_2.Value = 5.7;
            app.GKurEditFieldLabel = uilabel(app.IKurTab);
            app.GKurEditFieldLabel.Text = 'GKur';
            app.GKurEditField = uieditfield(app.IKurTab, 'numeric');
            app.GKurEditField.ValueChangedFcn = createCallbackFcn(app, @GKurKnobValueChanging, true);
            app.GKurEditField.Value = 0.16;
            app.IKssTab = uitab(app.TabGroup);
            app.IKssTab.Title = 'IKss';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Component Current')
            xlabel(app.UIAxes2, 'Time (ms)')
            ylabel(app.UIAxes2, 'pA/pF')
            app.UIAxes3 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes3, 'Time (ms)')
            ylabel(app.UIAxes3, 'pA/pF')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end